classdef Experiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Flow                            matlab.ui.container.Tree
        FLBlank                         matlab.ui.container.TreeNode
        StudyProcedureLabel             matlab.ui.control.Label
        AddActionPanel                  matlab.ui.container.Panel
        WhileLoopButton                 matlab.ui.control.Button
        ForLoopButton                   matlab.ui.control.Button
        RandomiserButton                matlab.ui.control.Button
        IfStatementButton               matlab.ui.control.Button
        VariableButton                  matlab.ui.control.Button
        CodeButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RoutineTab                      matlab.ui.container.Tab
        RoutineEdit                     matlab.ui.control.Button
        RoutineName                     matlab.ui.control.Label
        RoutineDuration                 matlab.ui.control.Label
        RoutineElementNo                matlab.ui.control.Label
        MoveButton                      matlab.ui.control.StateButton
        DeleteButton                    matlab.ui.control.Button
        VariableTab                     matlab.ui.container.Tab
        SetthevalueofEditFieldLabel     matlab.ui.control.Label
        SetthevalueofEditField          matlab.ui.control.EditField
        TobeequaltoEditFieldLabel       matlab.ui.control.Label
        TobeequaltoEditField            matlab.ui.control.EditField
        RandomValueCheckBox             matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        GeneratearandomnumberfromaLabel  matlab.ui.control.Label
        distributionLabel               matlab.ui.control.Label
        RoundedtothenearestSpinnerLabel  matlab.ui.control.Label
        RoundedtothenearestSpinner      matlab.ui.control.Spinner
        andSpinnerLabel                 matlab.ui.control.Label
        andSpinner                      matlab.ui.control.Spinner
        BetweenSpinnerLabel             matlab.ui.control.Label
        BetweenSpinner                  matlab.ui.control.Spinner
        decimalplaceLabel               matlab.ui.control.Label
        IfTab                           matlab.ui.container.Tab
        OrButton                        matlab.ui.control.Button
        IfField                         matlab.ui.control.EditField
        AndButton                       matlab.ui.control.Button
        IfDropDownLabel                 matlab.ui.control.Label
        IfDropDown                      matlab.ui.control.DropDown
        IsDropDownLabel                 matlab.ui.control.Label
        IfIsDropDown                    matlab.ui.control.DropDown
        ComparedtoEditFieldLabel        matlab.ui.control.Label
        IfComparedToField               matlab.ui.control.EditField
        ForTab                          matlab.ui.container.Tab
        HowmanyrepeatsSpinnerLabel      matlab.ui.control.Label
        ForRepeatsSpinner               matlab.ui.control.Spinner
        tothevariableLabel              matlab.ui.control.Label
        ForIteratorField                matlab.ui.control.EditField
        EachrepeataddSpinnerLabel       matlab.ui.control.Label
        ForIntervalSpinner              matlab.ui.control.Spinner
        startingatSpinnerLabel          matlab.ui.control.Label
        ForStartSpinner                 matlab.ui.control.Spinner
        WhileTab                        matlab.ui.container.Tab
        WhileField                      matlab.ui.control.EditField
        OrButton_2                      matlab.ui.control.Button
        AndButton_2                     matlab.ui.control.Button
        RepeatuntilDropDownLabel        matlab.ui.control.Label
        WhileDropDown                   matlab.ui.control.DropDown
        IsDropDown_2Label               matlab.ui.control.Label
        WhileIsDropDown                 matlab.ui.control.DropDown
        ComparedtoEditField_2Label      matlab.ui.control.Label
        WhileComparedToField            matlab.ui.control.EditField
        tothevariableLabel_2            matlab.ui.control.Label
        WhileIteratorField              matlab.ui.control.EditField
        EachrepeataddSpinnerLabel_2     matlab.ui.control.Label
        WhileIntervalSpinner            matlab.ui.control.Spinner
        startingatSpinnerLabel_2        matlab.ui.control.Label
        WhileStartSpinner               matlab.ui.control.Spinner
        RandomTab                       matlab.ui.container.Tab
        RandLab1                        matlab.ui.control.Label
        RandLab2                        matlab.ui.control.Label
        RandSpinner                     matlab.ui.control.Spinner
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RandFullButton                  matlab.ui.control.ToggleButton
        RandEvenButton                  matlab.ui.control.ToggleButton
        CodeTab                         matlab.ui.container.Tab
        TyperawMATLABcodeTextAreaLabel  matlab.ui.control.Label
        TyperawMATLABcodeTextArea       matlab.ui.control.TextArea
        NoneTab                         matlab.ui.container.Tab
        AddActionNextButton             matlab.ui.control.StateButton
        AddActionWithinButton           matlab.ui.control.StateButton
        RoutinePanel                    matlab.ui.container.Panel
        RoutineFileEdit                 matlab.ui.control.Button
        RoutineFileName                 matlab.ui.control.Label
        RoutineFileDur                  matlab.ui.control.Label
        RoutineFileElements             matlab.ui.control.Label
        RoutineFileDesign               matlab.ui.control.Table
        RoutineFileDesignLabel          matlab.ui.control.Label
        RoutineTree                     matlab.ui.container.Tree
        RTBlank                         matlab.ui.container.TreeNode
        AddRoutineWithinButton          matlab.ui.control.Button
        AddRoutineNextButton            matlab.ui.control.Button
        RoutinesLabel                   matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function loadDes(app, tree, des)
            % load BS factors
            i = strcmp({tree.NodeData}, 'B');
            par = tree(i);
            for n = 1:length(des.BS_Factors)
                child = uitreenode(par,  ...
                    'Text', des.BS_Factors(n).Name, ...
                    'NodeData', des.BS_Factors(n).Levels, ...
                    'Tag', 'B' ...
                    );
                for l = 1:length(des.BS_Factors(n).Levels)
                    uitreenode(child,  ...
                    'Text', des.BS_Factors(n).Levels(l).Text, ...
                    'NodeData', des.BS_Factors(n).Levels, ...
                    'Tag', des.BS_Factors(n).Levels(l).Tag ...
                    );
                end
            end
            
            % load WS Factors
            i = strcmp({tree.NodeData}, 'W');
            par = tree(i);
            for n = 1:length(des.WS_Factors)
                child = uitreenode(par,  ...
                    'Text', des.WS_Factors(n).Name, ...
                    'NodeData', des.WS_Factors(n).Levels, ...
                    'Tag', 'W' ...
                    );
                for l = 1:length(des.WS_Factors(n).Levels)
                    uitreenode(child,  ...
                    'Text', des.WS_Factors(n).Levels(l).Text, ...
                    'NodeData', des.WS_Factors(n).Levels, ...
                    'Tag', des.WS_Factors(n).Levels(l).Tag ...
                    );
                end
            end
            
            % load Measures
            i = strcmp({tree.NodeData}, 'M');
            par = tree(i);
            for n = 1:length(des.Measures)
                child = uitreenode(par,  ...
                    'Text', des.Measures(n).Name, ...
                    'NodeData', des.Measures(n).Type, ...
                    'Tag', 'M' ...
                    );
                typ = fieldnames(des.Measures(n).Type);
                ind = cell2mat(struct2cell(des.Measures(n).Type));
                uitreenode(child,  ...
                    'Text', typ{ind} ...
                    );
            end
        end
    
        function loadRT(app, tree, rt, name)
            routine = uitreenode(tree,  ...
                'Text', [name(1:end-4), ' | ', num2str(rt.TotalDur), ' ms'], ...
                'NodeData', ['Routines\', name], ...
                'Tag', 'R' ...
                );
            for e = 1:length(rt.Obj)
                uitreenode(routine,  ...
                'Text', [rt.Obj{e}.el.ElementName, ' | ', num2str(rt.Obj{1}.el.Dur(1)), ' ms'], ...
                'NodeData', rt.Dir{e}, ...
                'Tag', 'E' ...
                );
            end
        end
        
        function loadSpec(app, node)
            switch node.Tag
                case 'If'
                    app.IfDropDown.Value = node.NodeData.IfDropDown;
                    app.IfField.Value = node.NodeData.IfField;
                    app.IfIsDropDown.Value = node.NodeData.IfIsDropDown;
                    app.IfComparedToField.Value = node.NodeData.IfComparedToField;
                    
                case 'Else'
                    app.IfDropDown.Value = node.Parent.NodeData.IfDropDown;
                    app.IfField.Value = node.Parent.NodeData.IfField;
                    app.IfIsDropDown.Value = node.Parent.NodeData.IfIsDropDown;
                    app.IfComparedToField.Value = node.Parent.NodeData.IfComparedToField;
                    
                case 'While'
                    app.WhileDropDown.Value = node.NodeData.WhileDropDown;
                    app.WhileField.Value = node.NodeData.WhileField;
                    app.WhileIsDropDown.Value = node.NodeData.WhileIsDropDown;
                    app.WhileComparedToField.Value = node.NodeData.WhileComparedToField;
                    app.WhileIntervalSpinner.Value = node.NodeData.WhileIntervalSpinner;
                    app.WhileIteratorField.Value = node.NodeData.WhileIteratorField;
                    app.WhileStartSpinner.Value = node.NodeData.WhileStartSpinner;
                    
                case 'For'
                    app.ForRepeatsSpinner.Value = node.NodeData.ForRepeatsSpinner;
                    app.ForIntervalSpinner.Value = node.NodeData.ForIntervalSpinner;
                    app.ForIteratorField.Value = node.NodeData.ForIteratorField;
                    app.ForStartSpinner.Value = node.NodeData.ForStartSpinner;
                    
                case 'Random'
                    app.RandSpinner.Value = node.NodeData.RandSpinner;
                    app.RandSpinner.Limits(2) = length({node.Children});
                    app.RandFullButton.Value = node.NodeData.RandFullButton;
                    app.RandEvenButton.Value = node.NodeData.RandEvenButton;
                    
                case 'Routine'
                    app.RoutineName.Text = node.Text;
                    app.RoutineDuration.Text = [num2str(node.NodeData.rt.TotalDur), ' ms'];
                    app.RoutineElementNo.Text = [num2str(length(node.NodeData.rt.Line)), ' Elements'];
            end
        end
        
        function saveapp(app)
            ex = app.Flow.Children;
            save('Exp.mat', 'ex')
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center');
            app.TabGroup.SelectedTab = app.NoneTab;
            
            routineList = dir('Routines/*.mat');
            for r = 1:length(routineList)
                load(['Routines/' routineList(r).name]);
                uitreenode(app.RoutineTree,  app.RTBlank, 'before', ...
                    'Text', rt.Name, ...
                    'Tag', 'R', ...
                    'NodeData', rt ...
                    );
            end
            
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ForLoopButton, IfStatementButton, 
        % RandomiserButton, WhileLoopButton
        function AddNode(app, event)
            switch app.AddActionPanel.Tag
                case 'Next'
                    par = app.Flow;
                    sib = app.Flow.SelectedNodes;
                    
                case 'Within'
                    par = app.Flow.SelectedNodes;
            end
            
            switch event.Source
                case {app.AddRoutineNextButton, app.AddRoutineWithinButton}
                    tag = 'Routine';
                    [file, path] = uigetfile('*.mat', 'Select Routine', 'Routines\');
                    spec = struct('rt', load([path, file]), ...
                        'dir', [path, file] ...
                        );
                    spec.rt = spec.rt.rt;
                    name = file(1:end-4);
                case app.WhileLoopButton
                    tag = 'While';
                    spec = struct('WhileDropDown', 'Trial', ...
                        'WhileField', '', ...
                        'WhileIsDropDown', 'Less Than', ...
                        'WhileComparedToField', '', ...
                        'WhileIntervalSpinner', 1, ...
                        'WhileIteratorField', 'trial', ...
                        'WhileStartSpinner', 1 ...
                        );
                    name = [tag, '...'];
                case app.ForLoopButton
                    tag = 'For';
                    spec = struct('ForRepeatsSpinner', 1, ...
                        'ForIntervalSpinner', 1, ...
                        'ForIteratorField', 'trial', ...
                        'ForStartSpinner', 1 ...
                        );
                    name = [tag, '...'];
                case app.RandomiserButton
                    tag = 'Random';
                    spec = struct('RandSpinner', 0, ...
                        'RandFullButton', true, ...
                        'RandEvenButton', false ...
                        );
                    name = 'Randomise...';
                case app.IfStatementButton
                    tag = 'If';
                    spec = struct('IfDropDown', 'Trial', ...
                        'IfField', '', ...
                        'IfIsDropDown', 'Less Than', ...
                        'IfComparedToField', '' ...
                        );
                    name = [tag, '...'];
            end
            
            try
                child = uitreenode(par, sib, ...
                    'Text', name, ...
                    'NodeData', spec, ...
                    'Tag', tag ...
                    );
            catch
                child = uitreenode(par,  ...
                    'Text', name, ...
                    'NodeData', spec, ...
                    'Tag', tag ...
                    );
            end
            
            if event.Source == app.IfStatementButton
                uitreenode(child, ...
                    'Text', 'Else...', ...
                    'Tag', 'Else' ...
                    );
            end
            
            switch par.Tag
                case 'If'
                    i = strcmp({par.Children.Tag},'Else');
                    move(par.Children(i), par.Children(end), 'after');
            end
            
            app.AddActionPanel.Visible = false;
            app.AddRoutineNextButton.Value = false;
            app.AddRoutineWithinButton.Value = false;
            
        end

        % Selection changed function: Flow
        function FlowSelectionChanged(app, event)
            i = strcmp({app.TabGroup.Children.Title}, app.Flow.SelectedNodes.Tag); %identify matching tab
            
            switch app.Flow.SelectedNodes.Tag
                case 'If'
                    app.AddRoutineWithinButton.Enable = true;
                    app.MoveButton.Enable = true;
                    app.DeleteButton.Enable = true;
                case 'Else'
                    i = strcmp({app.TabGroup.Children.Title}, 'If'); %link to If tab
                    app.AddRoutineWithinButton.Enable = true;
                    app.MoveButton.Enable = false;
                    app.DeleteButton.Enable = false;
                case 'While'
                    app.AddRoutineWithinButton.Enable = true;
                    app.MoveButton.Enable = true;
                    app.DeleteButton.Enable = true;
                case 'For'
                    app.AddRoutineWithinButton.Enable = true;
                    app.MoveButton.Enable = true;
                    app.DeleteButton.Enable = true;
                case 'Random'
                    app.AddRoutineWithinButton.Enable = true;
                    app.MoveButton.Enable = true;
                    app.DeleteButton.Enable = true;
                case 'R'
                    app.AddRoutineWithinButton.Enable = false;
                    app.MoveButton.Enable = true;
                    app.DeleteButton.Enable = true;
            end
            
            %             switch app.Flow.SelectedNodes.Tag
            %                 case {'If', 'Else', 'While', 'For', 'Random'}
            %                     app.AddRoutineWithinButton.Enable = true;
            %                 case 'Routine'
            %                     app.AddRoutineWithinButton.Enable = false;
            %             end
            
            if sum(i)
                app.TabGroup.SelectedTab = app.TabGroup.Children(i); %switch to appropriate tab
            end
            
            if sum(~strcmp({event.SelectedNodes.Parent.Children.Tag},'Else')) < 2 %if the parent node contains less than 2 non-Else nodes
                app.MoveButton.Enable = false; %disable move
            end
            
            %             if ~strcmp({event.SelectedNodes.Tag},'Else')
            %                 app.DeleteButton.Enable = true;
            %             else
            %                 app.DeleteButton.Enable = false;
            %             end
            
            loadSpec(app, app.Flow.SelectedNodes)
            
            
            % if move is active, then move to below next selection
            if app.MoveButton.Value == true
                move(event.PreviousSelectedNodes, event.SelectedNodes, 'before');
                app.MoveButton.Value = false;
            end
        end

        % Value changed function: AddActionNextButton
        function AddActionNext(app, event)
            if event.Value
                app.AddActionPanel.Visible = true;
                app.AddActionPanel.Tag = 'Next';
                app.AddActionPanel.Position(1) = event.Source.Position(1) + event.Source.Parent.Position(1);
            else
                app.AddActionPanel.Visible = false;
                app.AddActionPanel.Tag = '';
            end
        end

        % Value changed function: AddActionWithinButton
        function AddActionWithin(app, event)
            if event.Value
                app.AddActionPanel.Visible = true;
                app.AddActionPanel.Tag = 'Within';
                app.AddActionPanel.Position(1) = event.Source.Position(1) + event.Source.Parent.Position(1);
            else
                app.AddActionPanel.Visible = false;
                app.AddActionPanel.Tag = '';
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delete(app.Flow.SelectedNodes)
            
            if isempty(app.Flow.Children)
                app.DeleteButton.Enable = false;
                app.MoveButton.Enable = false;
                app.AddRoutineWithinButton.Enable = false;
                i = strcmp({app.TabGroup.Children.Title}, ' ');
                app.TabGroup.SelectedTab = app.TabGroup.Children(i);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab = event.OldValue; %prevent changing tabs manually
        end

        % Value changed function: IfDropDown
        function IfDropDownValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.IfDropDown = event.Value;
            switch event.Value
                case 'Custom'
                    app.IfField.Enable = true;
            end
        end

        % Callback function: IfField, IfField
        function IfFieldValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.IfField = event.Value;
        end

        % Value changed function: IfIsDropDown
        function IfIsDropDownValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.IfIsDropDown = event.Value;
        end

        % Value changed function: IfComparedToField
        function IfComparedToFieldValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.IfComparedToField = event.Value;
        end

        % Value changed function: RandSpinner
        function RandSpinnerValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.RandSpinner = event.Value;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.Flow.SelectedNodes.NodeData.RandFullButton = event.NewValue == app.RandFullButton;
            app.Flow.SelectedNodes.NodeData.RandEvenButton = event.NewValue == app.RandEvenButton;
        end

        % Callback function: ForRepeatsSpinner, ForRepeatsSpinner
        function ForRepeatsSpinnerValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.ForRepeatsSpinner = event.Value;
        end

        % Callback function: ForIntervalSpinner, ForIntervalSpinner
        function ForIntervalSpinnerValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.ForIntervalSpinner = event.Value;
        end

        % Callback function: ForIteratorField, ForIteratorField
        function ForIteratorFieldValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.ForIteratorField = event.Value;
        end

        % Callback function: ForStartSpinner, ForStartSpinner
        function ForStartSpinnerValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.ForStartSpinner = event.Value;
        end

        % Value changed function: WhileDropDown
        function WhileDropDownValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.WhileDropDown = event.Value;
            switch event.Value
                case 'Custom'
                    app.WhileField.Enable = true;
            end
        end

        % Callback function: WhileField, WhileField
        function WhileFieldValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.WhileField = event.Value;
        end

        % Value changed function: WhileIsDropDown
        function WhileIsDropDownValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.WhileIsDropDown = event.Value;
        end

        % Callback function: WhileComparedToField, 
        % WhileComparedToField
        function WhileComparedToFieldValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.WhileComparedToField = event.Value;
        end

        % Button pushed function: RoutineEdit
        function EditRoutine(app, event)
            Routine
        end

        % Callback function: WhileIntervalSpinner, 
        % WhileIntervalSpinner
        function WhileIntervalSpinnerValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.WhileIntervalSpinner = event.Value;
        end

        % Callback function: WhileIteratorField, WhileIteratorField
        function WhileIteratorFieldValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.WhileIteratorField = event.Value;
        end

        % Callback function: WhileStartSpinner, WhileStartSpinner
        function WhileStartSpinnerValueChanged(app, event)
            app.Flow.SelectedNodes.NodeData.WhileStartSpinner = event.Value;
        end

        % Selection changed function: RoutineTree
        function RoutineTreeSelectionChanged(app, event)
            app.AddRoutineNextButton.Enable = isequal(event.SelectedNodes.Tag, 'R');
            for n = 1:length(app.RoutinePanel.Children)
                app.RoutinePanel.Children(n).Visible = isequal(event.SelectedNodes.Tag, 'R');
            end
            if isequal(event.SelectedNodes.Tag, 'R')
                app.RoutineFileName.Text = event.SelectedNodes.NodeData.Name;
                app.RoutineFileDur.Text = [num2str(event.SelectedNodes.NodeData.Dur) 'ms'];
                app.RoutineFileElements.Text = [num2str(length(event.SelectedNodes.NodeData.Elements)) ' Elements'];
                app.RoutineFileDesign.Data = event.SelectedNodes.NodeData.Factors;
            else
                app.RoutineFileName.Text = '';
                app.RoutineFileDur.Text = '';
                app.RoutineFileElements.Text = '';
                app.RoutineFileDesign.Data = [];
             end
        end

        % Value changed function: RandomValueCheckBox
        function RandomValueCheckBoxValueChanged(app, event)
            switch event.Value
                case true
                    app.Panel.Visible = true;
                    app.TobeequaltoEditField.Enable = false;
                    UpdateRand(app, event)
                case false
                    app.Panel.Visible = false;
                    app.TobeequaltoEditField.Enable = true;
            end
        end

        % Callback function: BetweenSpinner, BetweenSpinner, 
        % RoundedtothenearestSpinner, RoundedtothenearestSpinner, 
        % Switch, andSpinner, andSpinner
        function UpdateRand(app, event)
            app.RoundedtothenearestSpinner.Value = round(app.RoundedtothenearestSpinner.Value);
            app.TobeequaltoEditField.Value = ['round(' ...
                    num2str(app.BetweenSpinner.Value) ...
                    ' + ' app.Switch.Value '()*' ...
                    num2str(app.andSpinner.Value) ', ' ...
                    num2str(app.RoundedtothenearestSpinner.Value) ')'];
                
        end

        % Button pushed function: AddRoutineNextButton
        function AddRoutineNext(app, event)
            uitreenode(app.Flow, app.FLBlank, 'before', ...
                    'Text', app.RoutineTree.SelectedNodes.Text, ...
                    'Tag', app.RoutineTree.SelectedNodes.Tag ...
                    );
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Flow = uitree(app.UIFigure);
            app.Flow.SelectionChangedFcn = createCallbackFcn(app, @FlowSelectionChanged, true);
            app.FLBlank = uitreenode(app.Flow);
            app.FLBlank.Text = '';
            app.StudyProcedureLabel = uilabel(app.UIFigure);
            app.StudyProcedureLabel.Text = 'Study Procedure';
            app.AddActionPanel = uipanel(app.UIFigure);
            app.WhileLoopButton = uibutton(app.AddActionPanel, 'push');
            app.WhileLoopButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.WhileLoopButton.Text = 'While Loop';
            app.ForLoopButton = uibutton(app.AddActionPanel, 'push');
            app.ForLoopButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.ForLoopButton.Text = 'For Loop';
            app.RandomiserButton = uibutton(app.AddActionPanel, 'push');
            app.RandomiserButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.RandomiserButton.Text = 'Randomiser';
            app.IfStatementButton = uibutton(app.AddActionPanel, 'push');
            app.IfStatementButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.IfStatementButton.Text = 'If Statement';
            app.VariableButton = uibutton(app.AddActionPanel, 'push');
            app.VariableButton.Text = 'Variable';
            app.CodeButton = uibutton(app.AddActionPanel, 'push');
            app.CodeButton.Text = 'Code';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RoutineTab = uitab(app.TabGroup);
            app.RoutineTab.Title = 'Routine';
            app.RoutineEdit = uibutton(app.RoutineTab, 'push');
            app.RoutineEdit.ButtonPushedFcn = createCallbackFcn(app, @EditRoutine, true);
            app.RoutineEdit.Text = 'Edit';
            app.RoutineName = uilabel(app.RoutineTab);
            app.RoutineName.Text = 'Routine Name';
            app.RoutineDuration = uilabel(app.RoutineTab);
            app.RoutineDuration.Text = 'Duration';
            app.RoutineElementNo = uilabel(app.RoutineTab);
            app.RoutineElementNo.Text = '# Elements';
            app.MoveButton = uibutton(app.RoutineTab, 'state');
            app.MoveButton.Enable = 'off';
            app.MoveButton.Text = 'Move';
            app.DeleteButton = uibutton(app.RoutineTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.VariableTab = uitab(app.TabGroup);
            app.VariableTab.Title = 'Variable';
            app.SetthevalueofEditFieldLabel = uilabel(app.VariableTab);
            app.SetthevalueofEditFieldLabel.Text = 'Set the value of...';
            app.SetthevalueofEditField = uieditfield(app.VariableTab, 'text');
            app.TobeequaltoEditFieldLabel = uilabel(app.VariableTab);
            app.TobeequaltoEditFieldLabel.Text = 'To be equal to...';
            app.TobeequaltoEditField = uieditfield(app.VariableTab, 'text');
            app.RandomValueCheckBox = uicheckbox(app.VariableTab);
            app.RandomValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomValueCheckBoxValueChanged, true);
            app.RandomValueCheckBox.Text = 'Random Value';
            app.Panel = uipanel(app.VariableTab);
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'Uniform', 'Normal'};
            app.Switch.ItemsData = {'rand', 'randn'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @UpdateRand, true);
            app.Switch.Value = 'rand';
            app.GeneratearandomnumberfromaLabel = uilabel(app.Panel);
            app.GeneratearandomnumberfromaLabel.Text = {'Generate a random number '; 'from a...'};
            app.distributionLabel = uilabel(app.Panel);
            app.distributionLabel.Text = '...distribution.';
            app.RoundedtothenearestSpinnerLabel = uilabel(app.Panel);
            app.RoundedtothenearestSpinnerLabel.Text = 'Rounded to the nearest...';
            app.RoundedtothenearestSpinner = uispinner(app.Panel);
            app.RoundedtothenearestSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateRand, true);
            app.RoundedtothenearestSpinner.Limits = [0 Inf];
            app.RoundedtothenearestSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateRand, true);
            app.RoundedtothenearestSpinner.Value = 1;
            app.andSpinnerLabel = uilabel(app.Panel);
            app.andSpinnerLabel.Text = '...and...';
            app.andSpinner = uispinner(app.Panel);
            app.andSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateRand, true);
            app.andSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateRand, true);
            app.andSpinner.Value = 1;
            app.BetweenSpinnerLabel = uilabel(app.Panel);
            app.BetweenSpinnerLabel.Text = 'Between...';
            app.BetweenSpinner = uispinner(app.Panel);
            app.BetweenSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateRand, true);
            app.BetweenSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateRand, true);
            app.decimalplaceLabel = uilabel(app.Panel);
            app.decimalplaceLabel.Text = '...decimal place';
            app.IfTab = uitab(app.TabGroup);
            app.IfTab.Title = 'If';
            app.OrButton = uibutton(app.IfTab, 'push');
            app.OrButton.Enable = 'off';
            app.OrButton.Text = 'Or...';
            app.IfField = uieditfield(app.IfTab, 'text');
            app.IfField.ValueChangedFcn = createCallbackFcn(app, @IfFieldValueChanged, true);
            app.IfField.ValueChangingFcn = createCallbackFcn(app, @IfFieldValueChanged, true);
            app.IfField.Enable = 'off';
            app.AndButton = uibutton(app.IfTab, 'push');
            app.AndButton.Enable = 'off';
            app.AndButton.Text = 'And...';
            app.IfDropDownLabel = uilabel(app.IfTab);
            app.IfDropDownLabel.Text = 'If...';
            app.IfDropDown = uidropdown(app.IfTab);
            app.IfDropDown.Items = {'Trial', 'Time', 'Time Since...', 'Response to...', 'Custom'};
            app.IfDropDown.ValueChangedFcn = createCallbackFcn(app, @IfDropDownValueChanged, true);
            app.IfDropDown.Value = 'Trial';
            app.IsDropDownLabel = uilabel(app.IfTab);
            app.IsDropDownLabel.Text = 'Is...';
            app.IfIsDropDown = uidropdown(app.IfTab);
            app.IfIsDropDown.Items = {'Less Than', 'Less Than or Equal To', 'Equal To', 'Greater Than or Equal To', 'Greater Than', 'Not Equal To'};
            app.IfIsDropDown.ValueChangedFcn = createCallbackFcn(app, @IfIsDropDownValueChanged, true);
            app.IfIsDropDown.Value = 'Less Than';
            app.ComparedtoEditFieldLabel = uilabel(app.IfTab);
            app.ComparedtoEditFieldLabel.Text = 'Compared to...';
            app.IfComparedToField = uieditfield(app.IfTab, 'text');
            app.IfComparedToField.ValueChangedFcn = createCallbackFcn(app, @IfComparedToFieldValueChanged, true);
            app.ForTab = uitab(app.TabGroup);
            app.ForTab.Title = 'For';
            app.HowmanyrepeatsSpinnerLabel = uilabel(app.ForTab);
            app.HowmanyrepeatsSpinnerLabel.Text = 'How many repeats?';
            app.ForRepeatsSpinner = uispinner(app.ForTab);
            app.ForRepeatsSpinner.ValueChangingFcn = createCallbackFcn(app, @ForRepeatsSpinnerValueChanged, true);
            app.ForRepeatsSpinner.Limits = [1 Inf];
            app.ForRepeatsSpinner.ValueChangedFcn = createCallbackFcn(app, @ForRepeatsSpinnerValueChanged, true);
            app.ForRepeatsSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForRepeatsSpinner.Value = 1;
            app.tothevariableLabel = uilabel(app.ForTab);
            app.tothevariableLabel.Text = '...to the variable...';
            app.ForIteratorField = uieditfield(app.ForTab, 'text');
            app.ForIteratorField.ValueChangedFcn = createCallbackFcn(app, @ForIteratorFieldValueChanged, true);
            app.ForIteratorField.ValueChangingFcn = createCallbackFcn(app, @ForIteratorFieldValueChanged, true);
            app.ForIteratorField.Tooltip = {'Name a variable or variables (seperated by commas) to iterate each time this loop loops.'; 'You can refer back to this variable later if you want anything to change on each loop. "trial" will automatically change certain elements each time.'};
            app.ForIteratorField.Value = 'trial';
            app.EachrepeataddSpinnerLabel = uilabel(app.ForTab);
            app.EachrepeataddSpinnerLabel.Text = 'Each repeat, add...';
            app.ForIntervalSpinner = uispinner(app.ForTab);
            app.ForIntervalSpinner.ValueChangingFcn = createCallbackFcn(app, @ForIntervalSpinnerValueChanged, true);
            app.ForIntervalSpinner.Limits = [1 Inf];
            app.ForIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @ForIntervalSpinnerValueChanged, true);
            app.ForIntervalSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForIntervalSpinner.Value = 1;
            app.startingatSpinnerLabel = uilabel(app.ForTab);
            app.startingatSpinnerLabel.Text = '...starting at...';
            app.ForStartSpinner = uispinner(app.ForTab);
            app.ForStartSpinner.ValueChangingFcn = createCallbackFcn(app, @ForStartSpinnerValueChanged, true);
            app.ForStartSpinner.Limits = [1 Inf];
            app.ForStartSpinner.ValueChangedFcn = createCallbackFcn(app, @ForStartSpinnerValueChanged, true);
            app.ForStartSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForStartSpinner.Value = 1;
            app.WhileTab = uitab(app.TabGroup);
            app.WhileTab.Title = 'While';
            app.WhileField = uieditfield(app.WhileTab, 'text');
            app.WhileField.ValueChangedFcn = createCallbackFcn(app, @WhileFieldValueChanged, true);
            app.WhileField.ValueChangingFcn = createCallbackFcn(app, @WhileFieldValueChanged, true);
            app.WhileField.Enable = 'off';
            app.OrButton_2 = uibutton(app.WhileTab, 'push');
            app.OrButton_2.Enable = 'off';
            app.OrButton_2.Text = 'Or...';
            app.AndButton_2 = uibutton(app.WhileTab, 'push');
            app.AndButton_2.Enable = 'off';
            app.AndButton_2.Text = 'And...';
            app.RepeatuntilDropDownLabel = uilabel(app.WhileTab);
            app.RepeatuntilDropDownLabel.Text = 'Repeat until...';
            app.WhileDropDown = uidropdown(app.WhileTab);
            app.WhileDropDown.Items = {'Trial', 'Time', 'Time Since...', 'Custom'};
            app.WhileDropDown.ValueChangedFcn = createCallbackFcn(app, @WhileDropDownValueChanged, true);
            app.WhileDropDown.Value = 'Trial';
            app.IsDropDown_2Label = uilabel(app.WhileTab);
            app.IsDropDown_2Label.Text = 'Is...';
            app.WhileIsDropDown = uidropdown(app.WhileTab);
            app.WhileIsDropDown.Items = {'Less Than', 'Less Than or Equal To', 'Equal To', 'Greater Than or Equal To', 'Greater Than', 'Not Equal To'};
            app.WhileIsDropDown.ValueChangedFcn = createCallbackFcn(app, @WhileIsDropDownValueChanged, true);
            app.WhileIsDropDown.Value = 'Less Than';
            app.ComparedtoEditField_2Label = uilabel(app.WhileTab);
            app.ComparedtoEditField_2Label.Text = 'Compared to...';
            app.WhileComparedToField = uieditfield(app.WhileTab, 'text');
            app.WhileComparedToField.ValueChangedFcn = createCallbackFcn(app, @WhileComparedToFieldValueChanged, true);
            app.WhileComparedToField.ValueChangingFcn = createCallbackFcn(app, @WhileComparedToFieldValueChanged, true);
            app.tothevariableLabel_2 = uilabel(app.WhileTab);
            app.tothevariableLabel_2.Text = '...to the variable...';
            app.WhileIteratorField = uieditfield(app.WhileTab, 'text');
            app.WhileIteratorField.ValueChangedFcn = createCallbackFcn(app, @WhileIteratorFieldValueChanged, true);
            app.WhileIteratorField.ValueChangingFcn = createCallbackFcn(app, @WhileIteratorFieldValueChanged, true);
            app.WhileIteratorField.Tooltip = {'Name a variable or variables (seperated by commas) to iterate each time this loop loops.'; 'You can refer back to this variable later if you want anything to change on each loop. "trial" will automatically change certain elements each time.'};
            app.WhileIteratorField.Value = 'trial';
            app.EachrepeataddSpinnerLabel_2 = uilabel(app.WhileTab);
            app.EachrepeataddSpinnerLabel_2.Text = 'Each repeat, add...';
            app.WhileIntervalSpinner = uispinner(app.WhileTab);
            app.WhileIntervalSpinner.ValueChangingFcn = createCallbackFcn(app, @WhileIntervalSpinnerValueChanged, true);
            app.WhileIntervalSpinner.Limits = [1 Inf];
            app.WhileIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @WhileIntervalSpinnerValueChanged, true);
            app.WhileIntervalSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.WhileIntervalSpinner.Value = 1;
            app.startingatSpinnerLabel_2 = uilabel(app.WhileTab);
            app.startingatSpinnerLabel_2.Text = '...starting at...';
            app.WhileStartSpinner = uispinner(app.WhileTab);
            app.WhileStartSpinner.ValueChangingFcn = createCallbackFcn(app, @WhileStartSpinnerValueChanged, true);
            app.WhileStartSpinner.Limits = [1 Inf];
            app.WhileStartSpinner.ValueChangedFcn = createCallbackFcn(app, @WhileStartSpinnerValueChanged, true);
            app.WhileStartSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.WhileStartSpinner.Value = 1;
            app.RandomTab = uitab(app.TabGroup);
            app.RandomTab.Title = 'Random';
            app.RandLab1 = uilabel(app.RandomTab);
            app.RandLab1.Text = 'In random order run...';
            app.RandLab2 = uilabel(app.RandomTab);
            app.RandLab2.Text = '...of the nodes in this loop';
            app.RandSpinner = uispinner(app.RandomTab);
            app.RandSpinner.Limits = [0 Inf];
            app.RandSpinner.ValueChangedFcn = createCallbackFcn(app, @RandSpinnerValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.RandomTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RandFullButton = uitogglebutton(app.ButtonGroup);
            app.RandFullButton.Text = 'Fully Random';
            app.RandFullButton.Value = true;
            app.RandEvenButton = uitogglebutton(app.ButtonGroup);
            app.RandEvenButton.Text = 'Present Evenly';
            app.CodeTab = uitab(app.TabGroup);
            app.CodeTab.Title = 'Code';
            app.TyperawMATLABcodeTextAreaLabel = uilabel(app.CodeTab);
            app.TyperawMATLABcodeTextAreaLabel.Text = 'Type raw MATLAB code:';
            app.TyperawMATLABcodeTextArea = uitextarea(app.CodeTab);
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = ' ';
            app.AddActionNextButton = uibutton(app.UIFigure, 'state');
            app.AddActionNextButton.ValueChangedFcn = createCallbackFcn(app, @AddActionNext, true);
            app.AddActionNextButton.Text = 'Add Action Next';
            app.AddActionWithinButton = uibutton(app.UIFigure, 'state');
            app.AddActionWithinButton.ValueChangedFcn = createCallbackFcn(app, @AddActionWithin, true);
            app.AddActionWithinButton.Enable = 'off';
            app.AddActionWithinButton.Text = 'Add Action Within';
            app.RoutinePanel = uipanel(app.UIFigure);
            app.RoutineFileEdit = uibutton(app.RoutinePanel, 'push');
            app.RoutineFileEdit.Visible = 'off';
            app.RoutineFileEdit.Text = 'Edit';
            app.RoutineFileName = uilabel(app.RoutinePanel);
            app.RoutineFileName.Visible = 'off';
            app.RoutineFileName.Text = 'Routine Name';
            app.RoutineFileDur = uilabel(app.RoutinePanel);
            app.RoutineFileDur.Visible = 'off';
            app.RoutineFileDur.Text = 'Duration';
            app.RoutineFileElements = uilabel(app.RoutinePanel);
            app.RoutineFileElements.Visible = 'off';
            app.RoutineFileElements.Text = '# Elements';
            app.RoutineFileDesign = uitable(app.RoutinePanel);
            app.RoutineFileDesign.ColumnName = {'Factor'; 'Level'};
            app.RoutineFileDesign.RowName = {};
            app.RoutineFileDesign.Visible = 'off';
            app.RoutineFileDesignLabel = uilabel(app.RoutinePanel);
            app.RoutineFileDesignLabel.Visible = 'off';
            app.RoutineFileDesignLabel.Text = 'Design';
            app.RoutineTree = uitree(app.RoutinePanel);
            app.RoutineTree.SelectionChangedFcn = createCallbackFcn(app, @RoutineTreeSelectionChanged, true);
            app.RTBlank = uitreenode(app.RoutineTree);
            app.RTBlank.NodeData = 'N';
            app.RTBlank.Text = '';
            app.AddRoutineWithinButton = uibutton(app.RoutinePanel, 'push');
            app.AddRoutineWithinButton.Enable = 'off';
            app.AddRoutineWithinButton.Text = 'Add Within >>';
            app.AddRoutineNextButton = uibutton(app.RoutinePanel, 'push');
            app.AddRoutineNextButton.ButtonPushedFcn = createCallbackFcn(app, @AddRoutineNext, true);
            app.AddRoutineNextButton.Enable = 'off';
            app.AddRoutineNextButton.Text = 'Add Next >>';
            app.RoutinesLabel = uilabel(app.UIFigure);
            app.RoutinesLabel.Text = 'Routines';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Experiment

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end