classdef Pedestal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        CloseMenu                   matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        ComponentsMenu              matlab.ui.container.Menu
        ElementsMenu                matlab.ui.container.Menu
        NewElementMenu              matlab.ui.container.Menu
        ImportElementMenu           matlab.ui.container.Menu
        ManageElementsMenu          matlab.ui.container.Menu
        RoutinesMenu                matlab.ui.container.Menu
        NewRoutineMenu              matlab.ui.container.Menu
        ImportRoutineMenu           matlab.ui.container.Menu
        ManageRoutinesMenu          matlab.ui.container.Menu
        EditDesignMenu              matlab.ui.container.Menu
        ExperimentMenu              matlab.ui.container.Menu
        RunMenu                     matlab.ui.container.Menu
        StopMenu                    matlab.ui.container.Menu
        CompileMenu                 matlab.ui.container.Menu
        Tree                        matlab.ui.container.Tree
        WithinSubjectsFactorsNode   matlab.ui.container.TreeNode
        BetweenSubjectsFactorsNode  matlab.ui.container.TreeNode
        MeasuresNode                matlab.ui.container.TreeNode
        Node                        matlab.ui.container.TreeNode
        EditDesignButton            matlab.ui.control.Button
        ElementTree                 matlab.ui.container.Tree
        ManageRoutinesButton        matlab.ui.control.Button
        Tree_2                      matlab.ui.container.Tree
        Node_2                      matlab.ui.container.TreeNode
        ElementName                 matlab.ui.control.EditField
        ManageElementsButton_2      matlab.ui.control.Button
        ElementTree_2               matlab.ui.container.Tree
        ManageDataButton            matlab.ui.control.Button
        DesignLabel                 matlab.ui.control.Label
        ElementsLabel               matlab.ui.control.Label
        RoutinesLabel_3             matlab.ui.control.Label
        DataLabel                   matlab.ui.control.Label
        ManageProcedureButton       matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            addpath('Design', 'Elements', 'Routines', 'Icons');
            
            %% Splash
            im = imread('Splash.png'); %load splash image
            imsize = size(im); %get original image size
            imsize = imsize*(1000/max(imsize)); %transform so it fits within a 1000*1000 box
            
            splash = figure('Name', '');
            splash.MenuBar = 'none';
            splash.NumberTitle = 'off';
            splash.NumberTitle = 'off';
            splash.Position = [200,200,imsize(2),imsize(1)];
            
            splaxis = axes('Parent',splash);
            splaxis.Units = 'normalized';
            splaxis.Position = [0 0 1 1];
            image(splaxis, im);
            splaxis.TickDir = 'out';
            
            movegui(splash,'center'); %move to centre
            set(splash,'Visible','on')
            %%
            
            pause(5) %load content (placeholder)
            
            delete(splash)
            app.UIFigure.Visible = 'on';
            movegui(app.UIFigure,'center')
            
            set(0,'units','pixels');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New...';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.ComponentsMenu = uimenu(app.UIFigure);
            app.ComponentsMenu.Text = 'Components';
            app.ElementsMenu = uimenu(app.ComponentsMenu);
            app.ElementsMenu.Text = 'Elements';
            app.NewElementMenu = uimenu(app.ElementsMenu);
            app.NewElementMenu.Text = 'New Element';
            app.ImportElementMenu = uimenu(app.ElementsMenu);
            app.ImportElementMenu.Text = 'Import Element';
            app.ManageElementsMenu = uimenu(app.ElementsMenu);
            app.ManageElementsMenu.Text = 'Manage Elements';
            app.RoutinesMenu = uimenu(app.ComponentsMenu);
            app.RoutinesMenu.Text = 'Routines';
            app.NewRoutineMenu = uimenu(app.RoutinesMenu);
            app.NewRoutineMenu.Text = 'New Routine';
            app.ImportRoutineMenu = uimenu(app.RoutinesMenu);
            app.ImportRoutineMenu.Text = 'Import Routine';
            app.ManageRoutinesMenu = uimenu(app.RoutinesMenu);
            app.ManageRoutinesMenu.Text = 'Manage Routines';
            app.EditDesignMenu = uimenu(app.ComponentsMenu);
            app.EditDesignMenu.Text = 'Edit Design...';
            app.ExperimentMenu = uimenu(app.UIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.RunMenu = uimenu(app.ExperimentMenu);
            app.RunMenu.Text = 'Run';
            app.StopMenu = uimenu(app.ExperimentMenu);
            app.StopMenu.Text = 'Stop';
            app.CompileMenu = uimenu(app.ExperimentMenu);
            app.CompileMenu.Text = 'Compile...';
            app.Tree = uitree(app.UIFigure);
            app.WithinSubjectsFactorsNode = uitreenode(app.Tree);
            app.WithinSubjectsFactorsNode.NodeData = 'W';
            app.WithinSubjectsFactorsNode.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsNode = uitreenode(app.Tree);
            app.BetweenSubjectsFactorsNode.NodeData = 'B';
            app.BetweenSubjectsFactorsNode.Text = 'Between-Subjects Factors';
            app.MeasuresNode = uitreenode(app.Tree);
            app.MeasuresNode.NodeData = 'M';
            app.MeasuresNode.Text = 'Measures';
            app.Node = uitreenode(app.Tree);
            app.Node.NodeData = 'N';
            app.Node.Text = '';
            app.EditDesignButton = uibutton(app.UIFigure, 'push');
            app.EditDesignButton.Text = 'Edit Design';
            app.ElementTree = uitree(app.UIFigure);
            app.ManageRoutinesButton = uibutton(app.UIFigure, 'push');
            app.ManageRoutinesButton.Text = 'Manage Routines';
            app.Tree_2 = uitree(app.UIFigure);
            app.Node_2 = uitreenode(app.Tree_2);
            app.Node_2.NodeData = 'N';
            app.Node_2.Text = '';
            app.ElementName = uieditfield(app.UIFigure, 'text');
            app.ElementName.Value = 'Experiment Name';
            app.ManageElementsButton_2 = uibutton(app.UIFigure, 'push');
            app.ManageElementsButton_2.Text = 'Manage Elements';
            app.ElementTree_2 = uitree(app.UIFigure);
            app.ManageDataButton = uibutton(app.UIFigure, 'push');
            app.ManageDataButton.Text = 'Manage Data';
            app.DesignLabel = uilabel(app.UIFigure);
            app.DesignLabel.Text = 'Design';
            app.ElementsLabel = uilabel(app.UIFigure);
            app.ElementsLabel.Text = 'Elements';
            app.RoutinesLabel_3 = uilabel(app.UIFigure);
            app.RoutinesLabel_3.Text = 'Routines';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Text = 'Data';
            app.ManageProcedureButton = uibutton(app.UIFigure, 'push');
            app.ManageProcedureButton.Text = 'Manage Procedure';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pedestal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end