classdef Element < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Tree                           matlab.ui.container.Tree
        StimuliNode                    matlab.ui.container.TreeNode
        ResponsesNode                  matlab.ui.container.TreeNode
        CodeNode                       matlab.ui.container.TreeNode
        ElementName                    matlab.ui.control.EditField
        TabGroup                       matlab.ui.container.TabGroup
        ImageTab                       matlab.ui.container.Tab
        PresentationOrderLabel         matlab.ui.control.Label
        StimOrder                      matlab.ui.control.DropDown
        DeleteStimButton               matlab.ui.control.Button
        ImgDimLab                      matlab.ui.control.Label
        PositionLabel                  matlab.ui.control.Label
        X_SpinnerLabel                 matlab.ui.control.Label
        X_Spinner                      matlab.ui.control.Spinner
        X_CenterButton                 matlab.ui.control.Button
        Y_SpinnerLabel                 matlab.ui.control.Label
        Y_Spinner                      matlab.ui.control.Spinner
        Y_CenterButton                 matlab.ui.control.Button
        XSpinnerLabel_3                matlab.ui.control.Label
        Scale_Spinner                  matlab.ui.control.Spinner
        StimuliLabel                   matlab.ui.control.Label
        StimuliListBox                 matlab.ui.control.ListBox
        RespTab                        matlab.ui.container.Tab
        ButtonCoding                   matlab.ui.control.Table
        NewButtonMap                   matlab.ui.control.Button
        DeleteMeasure                  matlab.ui.control.Button
        EndroutineonresponseCheckBox   matlab.ui.control.CheckBox
        ResponsesMeasureDropDownLabel  matlab.ui.control.Label
        ResponsesMeasureDropDown       matlab.ui.control.DropDown
        ButtonMappingLabel             matlab.ui.control.Label
        CodeTab                        matlab.ui.container.Tab
        DeleteCode                     matlab.ui.control.Button
        CodeTextAreaLabel              matlab.ui.control.Label
        CodeTextArea                   matlab.ui.control.TextArea
        CodingName                     matlab.ui.control.EditField
        EdulogTab                      matlab.ui.container.Tab
        LoggersButtonGroup             matlab.ui.container.ButtonGroup
        TemperatureButton              matlab.ui.control.RadioButton
        LightButton                    matlab.ui.control.RadioButton
        PulseButton                    matlab.ui.control.RadioButton
        SoundButton                    matlab.ui.control.RadioButton
        HumidityButton                 matlab.ui.control.RadioButton
        MotionButton                   matlab.ui.control.RadioButton
        BloodPressureButton            matlab.ui.control.RadioButton
        HandDynamometerButton          matlab.ui.control.RadioButton
        GSRButton                      matlab.ui.control.RadioButton
        EKGButton                      matlab.ui.control.RadioButton
        PortEditFieldLabel             matlab.ui.control.Label
        PortEditField                  matlab.ui.control.EditField
        SamplesPerSecondSpinnerLabel   matlab.ui.control.Label
        SamplesPerSecondSpinner        matlab.ui.control.Spinner
        CodingName_2                   matlab.ui.control.EditField
        NoneTab                        matlab.ui.container.Tab
        AddNodePanel                   matlab.ui.container.Panel
        StimulusButton                 matlab.ui.control.Button
        CodeButton                     matlab.ui.control.Button
        MeasureButton                  matlab.ui.control.Button
        DurationmsLabel                matlab.ui.control.Label
        DurationSpinner                matlab.ui.control.Spinner
        NoResponseLimit                matlab.ui.control.CheckBox
        SaveButton                     matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        NewButton                      matlab.ui.control.StateButton
        FactorsButton                  matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        Window                         matlab.ui.control.UIAxes
        BlueSpinnerLabel               matlab.ui.control.Label
        BlueSpinner                    matlab.ui.control.Spinner
        GreenSpinnerLabel              matlab.ui.control.Label
        GreenSpinner                   matlab.ui.control.Spinner
        RedSpinnerLabel                matlab.ui.control.Label
        RedSpinner                     matlab.ui.control.Spinner
        X_Slider                       matlab.ui.control.Slider
        Y_Slider                       matlab.ui.control.Slider
        ScreenPreviewLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        rootdir % Description
        Factors % Description
    end
    
    methods (Access = public)            
        function ScreenSz(app, w, h, mw, mh)
            scale = min(mw / w, mh / h);
            
            app.Window.Position = [...
                app.Window.Position(1) + (mw - w*scale)/2, ... x
                app.Window.Position(2) + (mh - h*scale)/2, ... y
                floor([w*scale, h*scale]) ... w & h
                ];
            app.Window.XLim(2) = w;
            app.Window.YLim(2) = h;
            
            app.X_Slider.Position = floor([...
                app.Window.Position(1), ... x
                app.Window.Position(2) + app.Window.Position(4), ... y
                app.Window.Position(3), ... w
                3 ... h
                ]);
            app.X_Slider.Limits(2) = w;
            app.X_Spinner.Limits(2) = w;
            
            app.Y_Slider.Position = floor([...
                app.Window.Position(1), ... x
                app.Window.Position(2), ... y
                3, ... w
                app.Window.Position(4)... h
                ]);
            app.Y_Slider.Limits(2) = h;
            app.Y_Spinner.Limits(2) = h;
           
        end
        
        function StimPos(app, stim, x, y, s)
            dim = size(stim.NodeData.Img.Px);
            
            stim.NodeData.Pos.x = x;
            stim.NodeData.Rect.XData = [x - dim(1)*s/2, x + dim(1)*s/2];
            app.X_Slider.Value = x;
            app.X_Spinner.Value = x;
            
            stim.NodeData.Pos.y = y;
            stim.NodeData.Rect.YData = app.Y_Slider.Limits(2) - [y + dim(2)*s/2, y - dim(2)*s/2];
            app.Y_Slider.Value = y;
            app.Y_Spinner.Value = y;
            
            app.Scale_Spinner.Value = s;
            stim.NodeData.Pos.s = s;
        end
        
        function el = saveobj(app)            
            empty = [isempty(app.StimuliNode.Children), isempty(app.ResponsesNode.Children), isempty(app.CodeNode.Children)];
            nodes = {[app.StimuliNode], [app.ResponsesNode], [app.CodeNode]};
            nodes(empty) = {struct('Children', struct('NodeData', [NaN]))};
            
            el = struct(...
                'ElementName', app.ElementName.Value, ...
                'Stimuli', [nodes{1}.Children.NodeData], ...
                'Dur', app.DurationSpinner.Value, ...
                'Measures', [nodes{2}.Children.NodeData], ...
                'Coding', [nodes{3}.Children.NodeData], ...
                'Colour', [app.RedSpinner.Value, app.GreenSpinner.Value, app.BlueSpinner.Value] ...
                );
            
            save(['Elements\' app.ElementName.Value '.mat'], 'el')
        end
        
        function loadobj(app, el)
            app.ElementName.Value = el.ElementName;
            app.DurationSpinner.Value = el.Dur;
            
            for n = 1:length(el.Stimuli)
                if isstruct(el.Stimuli)
                    stim = uitreenode(app.StimuliNode, ...
                        'Text', el.Stimuli(n).Name, ...
                        'NodeData', struct('Name', el.Stimuli.Name, ...
                        'Order', el.Stimuli(n).Order, ...
                        'Pos', el.Stimuli(n).Pos, ...
                        'Dir', el.Stimuli(n).Dir, ...
                        'Img', el.Stimuli(n).Img), ...
                        'Tag', 'Stim' ...
                        );
                    
                    stim.NodeData.Rect = rectangle(app.Window, ...
                        'Position',[...
                        el.Stimuli(n).Pos.x, ... x
                        el.Stimuli(n).Pos.y, ... y
                        el.Stimuli(n).Img.Dim(1) * el.Stimuli.Pos.s, ... w
                        el.Stimuli(n).Img.Dim(2) * el.Stimuli.Pos.s ... h
                        ], ...
                        'FaceColor',[0,33,71]./255, ...
                        'EdgeColor',[0,33,71]./255);
                end
            end
            
            for n = 1:length(el.Measures)
                if isstruct(el.Measures)
                    uitreenode(app.ResponsesNode, ...
                        'Text', el.Measures(n).Name, ...
                        'NodeData', struct(...
                        'Name', el.Measures(n).Name, ...
                        'Mapping', el.Measures(n).Mapping), ...
                        'Tag', 'Meas' ...
                        );
                end
            end
            
            app.RedSpinner.Value = el.Colour(1);
            app.GreenSpinner.Value = el.Colour(3);
            app.BlueSpinner.Value = el.Colour(2);
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center');
            app.UIFigure.Visible = 'on';
            
            app.TabGroup.SelectedTab = app.NoneTab;
            load([app.rootdir, 'Design\Screen.mat'], '-mat', 'dim');
            ScreenSz(app, dim(1), dim(2), app.Window.Position(3), app.Window.Position(4));
            
        end

        % Button pushed function: StimulusButton
        function NewStimulusNode(app, event)
            stim = uitreenode(app.StimuliNode, ...
                'Text', '..', ...
                'NodeData', struct( ...
                'Order', {'Constant'}, ...
                'Pos', struct('x', [1], 'y', [1], 's', [1]), ...
                'Dir', '..', ...
                'Img', struct('Px', [], 'Map', [], 'A', []), ...
                'Rect', [], ...
		'Node', [] ...
                ), ...
                'Tag', 'Stim' ...
                );

            expand(app.StimuliNode)
            app.Tree.SelectedNodes = stim;            
            event.Source.Parent.Visible = 'off';
            app.NewButton.Value = false;
            TreeSelectionChanged(app, app.Tree);
        end

        % Callback function: X_Slider, X_Slider, X_Spinner, X_Spinner
        function X_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                event.Value, ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
        end

        % Callback function: Y_Slider, Y_Slider, Y_Spinner, Y_Spinner
        function Y_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                event.Value, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
        end

        % Callback function: Scale_Spinner, Scale_Spinner
        function S_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                event.Value);
        end

        % Button pushed function: X_CenterButton
        function X_CenterButtonPushed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                app.Window.XLim(2)/2, ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
        end

        % Button pushed function: Y_CenterButton
        function Y_CenterButtonPushed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                app.Window.YLim(2)/2, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
        end

        % Callback function: BlueSpinner, BlueSpinner, GreenSpinner, 
        % GreenSpinner, RedSpinner, RedSpinner
        function Bg_Changed(app, event)
            spinners = [app.RedSpinner, app.GreenSpinner, app.BlueSpinner];
            app.Window.BackgroundColor(spinners == event.Source) = event.Value;
        end

        % Value changed function: NewButton
        function OpenNewInterface(app, event)
            app.AddNodePanel.Visible = event.Value;

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            switch app.Tree.SelectedNodes.Tag
                case 'Stim'
                    stim = dir([app.rootdir, 'Stimuli']);
                    stim = {stim.name};
                    stim = stim(~strcmp(stim, '.'));
                    app.StimuliListBox.Items = stim;

                    app.TabGroup.SelectedTab = app.ImageTab;
                    
                    app.StimOrder.Value = event.SelectedNodes.NodeData.Order;
                    app.X_Spinner.Value = event.SelectedNodes.NodeData.Pos.x;
                    app.X_Slider.Value = event.SelectedNodes.NodeData.Pos.x;
                    app.Y_Spinner.Value = event.SelectedNodes.NodeData.Pos.y;
                    app.Y_Slider.Value = event.SelectedNodes.NodeData.Pos.y;
                    app.Scale_Spinner.Value = event.SelectedNodes.NodeData.Pos.s;
                    dirArray(1:length(stim)) = deal({event.SelectedNodes.NodeData.Dir});
                    app.StimuliListBox.Value = stim{cellfun(@contains, dirArray, stim)};
                    
                case 'Meas'
                    app.TabGroup.SelectedTab = app.RespTab;
                    app.X_Slider.Visible = 'off';
                    app.Y_Slider.Visible = 'off';
                    app.ButtonCoding.Data = app.Tree.SelectedNodes.NodeData.Mapping;
                    load([app.rootdir, 'Design\Design.mat'])
                    app.ResponsesMeasureDropDown.Items = [{des.Measures.Name}, {'..'}];
                    app.ResponsesMeasureDropDown.Value = event.SelectedNodes.NodeData.Measures;
                    app.EndroutineonresponseCheckBox.Value = event.SelectedNodes.NodeData.End;
                    
                case 'Code'
                    app.TabGroup.SelectedTab = app.CodeTab;
                    app.X_Slider.Visible = 'off';
                    app.Y_Slider.Visible = 'off';
                    
                    app.CodingName.Value = app.Tree.SelectedNodes.NodeData.Name;
                    app.CodeTextArea.Value = app.Tree.SelectedNodes.NodeData.Code;
                    
                otherwise
                    app.TabGroup.SelectedTab = app.NoneTab;
            end
        end

        % Callback function: StimuliListBox
        function StimSelectionChanged(app, event)
            app.Tree.SelectedNodes.Text = event.Value;
            try
                delete(app.Tree.SelectedNodes.NodeData.Rect)
            end
            
            switch event.Value
                case '..'
                    app.Tree.SelectedNodes.NodeData.Img = struct('Px', [], 'Map', [], 'A', []);
                    app.Tree.SelectedNodes.NodeData.Dir = event.Value;
                    app.X_Slider.Visible = 'off';
                    app.Y_Slider.Visible = 'off';
                    try
                        delete(app.Tree.SelectedNodes.NodeData.Rect)
                    end
                otherwise
                    [img.Px, img.Map, img.A] = imread([app.rootdir 'Stimuli\' event.Value]);
                    app.Tree.SelectedNodes.NodeData.Img = img;
                    app.Tree.SelectedNodes.NodeData.Dir = [app.rootdir 'Stimuli\' event.Value];
                    app.X_Slider.Visible = 'on';
                    app.Y_Slider.Visible = 'on';
                    
                    app.Tree.SelectedNodes.NodeData.Rect = imshow(img.Px, img.Map, ...
                        'Parent', app.Window);
                    if ~isempty(img.A)
                        app.Tree.SelectedNodes.NodeData.Rect.AlphaData = img.A;
                    end
                    StimPos(app, app.Tree.SelectedNodes, ...
                        app.Window.XLim(2)/2, ...
                        app.Window.YLim(2)/2, ...
                        1);
            end

            dim = size(app.Tree.SelectedNodes.NodeData.Img.Px);
            app.ImgDimLab.Text = [num2str(dim(1)) ' x ' num2str(dim(2))];
            
            
             hold(app.Window, 'on') ;  
        end

        % Selection change function: TabGroup
        function KeepTab(app, event)
            app.TabGroup.SelectedTab = event.OldValue; %prevent changing tabs manually

        end

        % Value changed function: NoResponseLimit
        function InfiniteDuration(app, event)
            if event.Value == true
                app.DurationSpinner.Enable = false;
                app.DurationSpinner.Value = Inf;
            else
                app.DurationSpinner.Enable = true;
            end
            
        end

        % Button pushed function: SaveButton
        function Save(app, event)
            saveobj(app);
            delete(app);
            
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            delete(app);
            
        end

        % Button pushed function: NewButtonMap
        function NewButtonMapPushed(app, event)
            app.ButtonCoding.Data = [app.ButtonCoding.Data; {'Button'} {'Code'}];
            app.Tree.SelectedNodes.NodeData.Mapping = app.ButtonCoding.Data;
        end

        % Button pushed function: MeasureButton
        function NewMeasureNode(app, event)
            meas = uitreenode(app.ResponsesNode, ...
                'Text', '..', ...
                'NodeData', struct(...
                'Mapping', {{'Button', 'Code'}}, ...
                'Measures', {'..'}, ...
                'End', false ...
                ), ...
                'Tag', 'Meas' ...
                );
            app.Tree.SelectedNodes = meas;
            TreeSelectionChanged(app, app.Tree);
            app.AddNodePanel.Visible = false;
            expand(app.ResponsesNode)
            app.NewButton.Value = false;
        end

        % Button pushed function: DeleteCode, DeleteMeasure, 
        % DeleteStimButton
        function DeleteNode(app, event)
            try
                delete(app.Tree.SelectedNodes.NodeData.Rect)
            end
            delete(app.Tree.SelectedNodes);
            app.TabGroup.SelectedTab = app.NoneTab;
            
        end

        % Callback function: CodingName, CodingName
        function UpdateCodeNode(app, event)
            app.Tree.SelectedNodes.NodeData.Name = app.CodingName.Value;
            app.Tree.SelectedNodes.Text = app.CodingName.Value;
            app.Tree.SelectedNodes.NodeData.Code = app.CodeTextArea.Value;

        end

        % Button pushed function: CodeButton
        function NewCodeNode(app, event)
            co = uitreenode(app.CodeNode, ...
                'Text', 'Code Component Name', ...
                'NodeData', struct(...
                'Name', 'Code Component Name', ...
                'Code', ''), ...
                'Tag', 'Code' ...
                );
            app.Tree.SelectedNodes = co;
            TreeSelectionChanged(app, app.Tree);
            app.AddNodePanel.Visible = false;
            expand(app.CodeNode)
            app.NewButton.Value = false;
        end

        % Callback function: ButtonCoding, 
        % EndroutineonresponseCheckBox, ResponsesMeasureDropDown
        function UpdateResponseNode(app, event)
            i = sum(cellfun(@isempty, app.ButtonCoding.Data),2) == 2;
            app.ButtonCoding.Data(i,:) = [];
            
            app.Tree.SelectedNodes.NodeData.Measures = app.ResponsesMeasureDropDown.Value;
            app.Tree.SelectedNodes.NodeData.Mapping = app.ButtonCoding.Data;
            app.Tree.SelectedNodes.NodeData.End = app.EndroutineonresponseCheckBox.Value;
            
            app.Tree.SelectedNodes.Text = app.ResponsesMeasureDropDown.Value;
        end

        % Button pushed function: FactorsButton
        function EditFactors(app, event)
            f = Factors;
            f.Parent = app;
            f.init();
            if ~isempty(app.Factors)
                f.loadobj(app.Factors);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.StimuliNode = uitreenode(app.Tree);
            app.StimuliNode.Text = 'Stimuli';
            app.ResponsesNode = uitreenode(app.Tree);
            app.ResponsesNode.Text = 'Responses';
            app.CodeNode = uitreenode(app.Tree);
            app.CodeNode.Text = 'Code';
            app.ElementName = uieditfield(app.UIFigure, 'text');
            app.ElementName.Value = 'Element Name';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @KeepTab, true);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.PresentationOrderLabel = uilabel(app.ImageTab);
            app.PresentationOrderLabel.Text = 'Presentation Order:';
            app.StimOrder = uidropdown(app.ImageTab);
            app.StimOrder.Items = {'Constant'};
            app.StimOrder.Value = 'Constant';
            app.DeleteStimButton = uibutton(app.ImageTab, 'push');
            app.DeleteStimButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteStimButton.Text = 'Delete';
            app.ImgDimLab = uilabel(app.ImageTab);
            app.ImgDimLab.Text = '';
            app.PositionLabel = uilabel(app.ImageTab);
            app.PositionLabel.Text = 'Position';
            app.X_SpinnerLabel = uilabel(app.ImageTab);
            app.X_SpinnerLabel.Text = 'X:';
            app.X_Spinner = uispinner(app.ImageTab);
            app.X_Spinner.ValueChangingFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Spinner.Limits = [1 750];
            app.X_Spinner.ValueChangedFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Spinner.Value = 1;
            app.X_CenterButton = uibutton(app.ImageTab, 'push');
            app.X_CenterButton.ButtonPushedFcn = createCallbackFcn(app, @X_CenterButtonPushed, true);
            app.X_CenterButton.Text = 'Center';
            app.Y_SpinnerLabel = uilabel(app.ImageTab);
            app.Y_SpinnerLabel.Text = 'Y:';
            app.Y_Spinner = uispinner(app.ImageTab);
            app.Y_Spinner.ValueChangingFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Spinner.Limits = [1 500];
            app.Y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Spinner.Value = 1;
            app.Y_CenterButton = uibutton(app.ImageTab, 'push');
            app.Y_CenterButton.ButtonPushedFcn = createCallbackFcn(app, @Y_CenterButtonPushed, true);
            app.Y_CenterButton.Text = 'Center';
            app.XSpinnerLabel_3 = uilabel(app.ImageTab);
            app.XSpinnerLabel_3.Text = 'Scale:';
            app.Scale_Spinner = uispinner(app.ImageTab);
            app.Scale_Spinner.Step = 0.25;
            app.Scale_Spinner.ValueChangingFcn = createCallbackFcn(app, @S_Changed, true);
            app.Scale_Spinner.Limits = [0 Inf];
            app.Scale_Spinner.ValueChangedFcn = createCallbackFcn(app, @S_Changed, true);
            app.Scale_Spinner.Value = 1;
            app.StimuliLabel = uilabel(app.ImageTab);
            app.StimuliLabel.Text = 'Stimuli:';
            app.StimuliListBox = uilistbox(app.ImageTab);
            app.StimuliListBox.Items = {};
            app.StimuliListBox.ValueChangedFcn = createCallbackFcn(app, @StimSelectionChanged, true);
            app.StimuliListBox.Value = {};
            app.RespTab = uitab(app.TabGroup);
            app.RespTab.Title = 'Resp';
            app.ButtonCoding = uitable(app.RespTab);
            app.ButtonCoding.ColumnName = {'If they press...'; 'Record it as...'};
            app.ButtonCoding.RowName = {};
            app.ButtonCoding.ColumnEditable = true;
            app.ButtonCoding.CellEditCallback = createCallbackFcn(app, @UpdateResponseNode, true);
            app.NewButtonMap = uibutton(app.RespTab, 'push');
            app.NewButtonMap.ButtonPushedFcn = createCallbackFcn(app, @NewButtonMapPushed, true);
            app.NewButtonMap.Text = 'Add Map';
            app.DeleteMeasure = uibutton(app.RespTab, 'push');
            app.DeleteMeasure.ButtonPushedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteMeasure.Text = 'Delete';
            app.EndroutineonresponseCheckBox = uicheckbox(app.RespTab);
            app.EndroutineonresponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateResponseNode, true);
            app.EndroutineonresponseCheckBox.Text = 'End routine on response';
            app.ResponsesMeasureDropDownLabel = uilabel(app.RespTab);
            app.ResponsesMeasureDropDownLabel.Text = 'Responses Measure...';
            app.ResponsesMeasureDropDown = uidropdown(app.RespTab);
            app.ResponsesMeasureDropDown.Items = {};
            app.ResponsesMeasureDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateResponseNode, true);
            app.ResponsesMeasureDropDown.Value = {};
            app.ButtonMappingLabel = uilabel(app.RespTab);
            app.ButtonMappingLabel.Text = 'Button Mapping:';
            app.CodeTab = uitab(app.TabGroup);
            app.CodeTab.Title = 'Code';
            app.DeleteCode = uibutton(app.CodeTab, 'push');
            app.DeleteCode.ButtonPushedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteCode.Text = 'Delete';
            app.CodeTextAreaLabel = uilabel(app.CodeTab);
            app.CodeTextAreaLabel.Text = 'Code:';
            app.CodeTextArea = uitextarea(app.CodeTab);
            app.CodingName = uieditfield(app.CodeTab, 'text');
            app.CodingName.ValueChangedFcn = createCallbackFcn(app, @UpdateCodeNode, true);
            app.CodingName.ValueChangingFcn = createCallbackFcn(app, @UpdateCodeNode, true);
            app.CodingName.Value = 'Code Component Name';
            app.EdulogTab = uitab(app.TabGroup);
            app.EdulogTab.Title = 'Edulog';
            app.LoggersButtonGroup = uibuttongroup(app.EdulogTab);
            app.LoggersButtonGroup.Title = 'Loggers';
            app.TemperatureButton = uiradiobutton(app.LoggersButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.TemperatureButton.Value = true;
            app.LightButton = uiradiobutton(app.LoggersButtonGroup);
            app.LightButton.Text = 'Light';
            app.PulseButton = uiradiobutton(app.LoggersButtonGroup);
            app.PulseButton.Text = 'Pulse';
            app.SoundButton = uiradiobutton(app.LoggersButtonGroup);
            app.SoundButton.Text = 'Sound';
            app.HumidityButton = uiradiobutton(app.LoggersButtonGroup);
            app.HumidityButton.Text = 'Humidity';
            app.MotionButton = uiradiobutton(app.LoggersButtonGroup);
            app.MotionButton.Text = 'Motion';
            app.BloodPressureButton = uiradiobutton(app.LoggersButtonGroup);
            app.BloodPressureButton.Text = 'BloodPressure';
            app.HandDynamometerButton = uiradiobutton(app.LoggersButtonGroup);
            app.HandDynamometerButton.Text = 'HandDynamometer';
            app.GSRButton = uiradiobutton(app.LoggersButtonGroup);
            app.GSRButton.Text = 'GSR';
            app.EKGButton = uiradiobutton(app.LoggersButtonGroup);
            app.EKGButton.Text = 'EKG';
            app.PortEditFieldLabel = uilabel(app.EdulogTab);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.EdulogTab, 'text');
            app.SamplesPerSecondSpinnerLabel = uilabel(app.EdulogTab);
            app.SamplesPerSecondSpinnerLabel.Text = 'Samples Per Second';
            app.SamplesPerSecondSpinner = uispinner(app.EdulogTab);
            app.SamplesPerSecondSpinner.Step = 0.5;
            app.SamplesPerSecondSpinner.Limits = [0.5 5];
            app.SamplesPerSecondSpinner.Value = 0.5;
            app.CodingName_2 = uieditfield(app.EdulogTab, 'text');
            app.CodingName_2.Value = 'Edulog Measure Name';
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = ' ';
            app.AddNodePanel = uipanel(app.UIFigure);
            app.AddNodePanel.Visible = 'off';
            app.StimulusButton = uibutton(app.AddNodePanel, 'push');
            app.StimulusButton.ButtonPushedFcn = createCallbackFcn(app, @NewStimulusNode, true);
            app.StimulusButton.Text = 'Stimulus';
            app.CodeButton = uibutton(app.AddNodePanel, 'push');
            app.CodeButton.ButtonPushedFcn = createCallbackFcn(app, @NewCodeNode, true);
            app.CodeButton.Text = 'Code';
            app.MeasureButton = uibutton(app.AddNodePanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @NewMeasureNode, true);
            app.MeasureButton.Text = 'Measure';
            app.DurationmsLabel = uilabel(app.UIFigure);
            app.DurationmsLabel.Text = 'Duration (ms):';
            app.DurationSpinner = uispinner(app.UIFigure);
            app.DurationSpinner.Step = 100;
            app.DurationSpinner.RoundFractionalValues = 'on';
            app.DurationSpinner.Value = 100;
            app.NoResponseLimit = uicheckbox(app.UIFigure);
            app.NoResponseLimit.ValueChangedFcn = createCallbackFcn(app, @InfiniteDuration, true);
            app.NoResponseLimit.Text = '∞';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Save, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.NewButton = uibutton(app.UIFigure, 'state');
            app.NewButton.ValueChangedFcn = createCallbackFcn(app, @OpenNewInterface, true);
            app.NewButton.Text = 'New';
            app.FactorsButton = uibutton(app.UIFigure, 'push');
            app.FactorsButton.ButtonPushedFcn = createCallbackFcn(app, @EditFactors, true);
            app.FactorsButton.Text = 'Factors...';
            app.Panel = uipanel(app.UIFigure);
            app.Window = uiaxes(app.Panel);
            app.Window.XLim = [1 750];
            app.Window.YLim = [1 500];
            app.Window.XTick = [];
            app.Window.YTick = [];
            app.Window.Visible = 'off';
            app.BlueSpinnerLabel = uilabel(app.Panel);
            app.BlueSpinnerLabel.Text = 'Blue';
            app.BlueSpinner = uispinner(app.Panel);
            app.BlueSpinner.Step = 0.01;
            app.BlueSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.BlueSpinner.Limits = [0 1];
            app.BlueSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.BlueSpinner.Value = 0.5;
            app.GreenSpinnerLabel = uilabel(app.Panel);
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.Panel);
            app.GreenSpinner.Step = 0.01;
            app.GreenSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.GreenSpinner.Limits = [0 1];
            app.GreenSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.GreenSpinner.Value = 0.5;
            app.RedSpinnerLabel = uilabel(app.Panel);
            app.RedSpinnerLabel.Text = 'Red';
            app.RedSpinner = uispinner(app.Panel);
            app.RedSpinner.Step = 0.01;
            app.RedSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.RedSpinner.Limits = [0 1];
            app.RedSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.RedSpinner.Value = 0.5;
            app.X_Slider = uislider(app.Panel);
            app.X_Slider.Limits = [1 750];
            app.X_Slider.MajorTicks = [];
            app.X_Slider.ValueChangedFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Slider.ValueChangingFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Slider.MinorTicks = [];
            app.X_Slider.Visible = 'off';
            app.X_Slider.Value = 375;
            app.Y_Slider = uislider(app.Panel);
            app.Y_Slider.Limits = [1 500];
            app.Y_Slider.MajorTicks = [];
            app.Y_Slider.ValueChangedFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Slider.ValueChangingFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Slider.MinorTicks = [];
            app.Y_Slider.Visible = 'off';
            app.Y_Slider.Value = 250;
            app.ScreenPreviewLabel = uilabel(app.Panel);
            app.ScreenPreviewLabel.Text = 'Screen Preview';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Element

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end