classdef Pedestal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewExperimentMenu           matlab.ui.container.Menu
        OpenExperimentMenu          matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        CloseMenu                   matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        ComponentsMenu              matlab.ui.container.Menu
        StimuliMenu                 matlab.ui.container.Menu
        NewStimulusMenu             matlab.ui.container.Menu
        ElementsMenu                matlab.ui.container.Menu
        NewElementMenu              matlab.ui.container.Menu
        ImportElementMenu           matlab.ui.container.Menu
        RoutinesMenu                matlab.ui.container.Menu
        NewRoutineMenu              matlab.ui.container.Menu
        ImportRoutineMenu           matlab.ui.container.Menu
        EditDesignMenu              matlab.ui.container.Menu
        EditProcedureMenu           matlab.ui.container.Menu
        ExperimentMenu              matlab.ui.container.Menu
        RunMenu                     matlab.ui.container.Menu
        StopMenu                    matlab.ui.container.Menu
        CompileMenu                 matlab.ui.container.Menu
        Tree                        matlab.ui.container.Tree
        WithinSubjectsFactorsNode   matlab.ui.container.TreeNode
        BetweenSubjectsFactorsNode  matlab.ui.container.TreeNode
        MeasuresNode                matlab.ui.container.TreeNode
        Node                        matlab.ui.container.TreeNode
        EditDesignButton            matlab.ui.control.Button
        ElementName                 matlab.ui.control.EditField
        ElementTree_2               matlab.ui.container.Tree
        ManageDataButton            matlab.ui.control.Button
        DesignLabel                 matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        ProcedureTree               matlab.ui.container.Tree
        PTBlank                     matlab.ui.container.TreeNode
        ProcedureLabel              matlab.ui.control.Label
        EditProcedureButton         matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        ComponentManagerLabel       matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        StimulusTab                 matlab.ui.container.Tab
        StimPreview                 matlab.ui.control.UIAxes
        DeleteButton_2              matlab.ui.control.Button
        ElementTab                  matlab.ui.container.Tab
        DeleteButton                matlab.ui.control.Button
        EditButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        BgPreview                   matlab.ui.control.UIAxes
        BackgroundColourLabel       matlab.ui.control.Label
        ContentsLabel               matlab.ui.control.Label
        ElementNameLabel            matlab.ui.control.Label
        DurationLabel               matlab.ui.control.Label
        RGBLabel                    matlab.ui.control.Label
        RoutineTab                  matlab.ui.container.Tab
        RoutineNameLabel            matlab.ui.control.Label
        RoutineDuration             matlab.ui.control.Label
        ElementsLabel               matlab.ui.control.Label
        RoutineElements             matlab.ui.container.Tree
        ElDurLabel                  matlab.ui.control.Label
        FactorsLabel                matlab.ui.control.Label
        RoutineFactors              matlab.ui.control.Table
        DeleteButton_3              matlab.ui.control.Button
        EditButton_2                matlab.ui.control.Button
        NoneTab                     matlab.ui.container.Tab
        ComponentTree               matlab.ui.container.Tree
        StimuliNode                 matlab.ui.container.TreeNode
        ElementsNode_2              matlab.ui.container.TreeNode
        RoutinesNode                matlab.ui.container.TreeNode
        Node_2                      matlab.ui.container.TreeNode
        AddComponentPanel           matlab.ui.container.Panel
        StimulusButton              matlab.ui.control.Button
        ElementButton               matlab.ui.control.Button
        RoutineButton               matlab.ui.control.Button
        NewButton_2                 matlab.ui.control.StateButton
        RefreshButton               matlab.ui.control.Button
        DataLabel                   matlab.ui.control.Label
        ActionDetails               matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        rootdir % Description
    end
    
    methods (Access = public)        
        function loadproc(app, file)
            load(file)
            i = app.ProcedureTree.Children ~= app.PTBlank;
            delete(app.ProcedureTree.Children(i));
            for n = 1:length(pr)
                parent = uitreenode(app.ProcedureTree, app.PTBlank, 'before', ...
                    'Text', pr(n).Text, ...
                    'Tag', pr(n).Tag, ...
                    'NodeData', pr(n).NodeData ...
                    );
                switch parent.Tag
                    case {'For', 'If', 'Else', 'While', 'Random'}
                        procCycle(app, pr(n).Children, parent);
                    case 'R'
                        for e = 1:length(pr(n).NodeData.Elements)
                            el = pr(n).NodeData.Elements{e};
                            uitreenode(parent, ...
                                'Text', el.ElementName, ...
                                'Tag', 'El', ...
                                'NodeData', el ...
                                );
                        end
                end
            end
        end
        function procCycle(app, children, parent)
            for n = 1:length(children)
                child = uitreenode(parent, ...
                    'Text', children(n).Text, ...
                    'Tag', children(n).Tag, ...
                    'NodeData', children(n).NodeData ...
                    );
                switch child.Tag
                    case {'For', 'If', 'Else', 'While', 'Random'}
                        procCycle(app, children(n).Children, child);
                    case 'R'
                        for e = 1:length(children(n).NodeData.Elements)
                            el = children(n).NodeData.Elements{e};
                            uitreenode(child, ...
                                'Text', el.ElementName, ...
                                'Tag', 'El', ...
                                'NodeData', el ...
                                );
                        end
                end
            end
        end
        
        function loaddes(app, file)
            load(file);
            
            % load BS factors
            delete(app.BetweenSubjectsFactorsNode.Children)
            for n = 1:length(des.BS_Factors)
                parent = uitreenode(app.BetweenSubjectsFactorsNode,  ...
                    'Text', des.BS_Factors(n).Name, ...
                    'NodeData', des.BS_Factors(n).Levels, ...
                    'Tag', 'B' ...
                    );
                for c = 1:length(parent.NodeData)
                    uitreenode(parent,  ...
                    'Text', parent.NodeData(c).Text, ...
                    'Tag', 'L' ...
                    );
                end
            end
            expand(app.BetweenSubjectsFactorsNode)
            
            % load WS Factors
            delete(app.WithinSubjectsFactorsNode.Children)
            for n = 1:length(des.WS_Factors)
                parent = uitreenode(app.WithinSubjectsFactorsNode,  ...
                    'Text', des.WS_Factors(n).Name, ...
                    'NodeData', des.WS_Factors(n).Levels, ...
                    'Tag', 'W' ...
                    );
                for c = 1:length(parent.NodeData)
                    uitreenode(parent,  ...
                    'Text', parent.NodeData(c).Text, ...
                    'Tag', 'L' ...
                    );
                end
            end
            expand(app.WithinSubjectsFactorsNode)
            
            % load Measures
            delete(app.MeasuresNode.Children)
            for n = 1:length(des.Measures)
                uitreenode(app.MeasuresNode,  ...
                    'Text', des.Measures(n).Name, ...
                    'NodeData', des.Measures(n).Type, ...
                    'Tag', 'M' ...
                    );
            end
            expand(app.MeasuresNode)
        end
    
        function loadcomp(app)
            % load Stimuli
            stim = dir([app.rootdir, 'Stimuli']);
            stim = stim(~strcmp({stim.name}, '..') & ~strcmp({stim.name}, '.'));
            delete(app.StimuliNode.Children)
            for n = 1:length(stim)
                uitreenode(app.StimuliNode,  ...
                    'Text', stim(n).name, ...
                    'NodeData', [app.rootdir, 'Stimuli\', stim(n).name], ...
                    'Tag', 'Stim' ...
                    );
            end
            expand(app.StimuliNode)
            
            % load Elements
            emts = dir([app.rootdir, 'Elements']);
            emts = emts(~strcmp({emts.name}, '..') & ~strcmp({emts.name}, '.'));
            delete(app.ElementsNode_2.Children)
            for n = 1:length(emts)
                load([app.rootdir, 'Elements\', emts(n).name])
                uitreenode(app.ElementsNode_2,  ...
                    'Text', emts(n).name, ...
                    'NodeData', el, ...
                    'Tag', 'Element' ...
                    );
            end
            expand(app.ElementsNode_2)
            
            % load Routines
            rts = dir([app.rootdir, 'Routines']);
            rts = rts(~strcmp({rts.name}, '..') & ~strcmp({rts.name}, '.'));
            delete(app.RoutinesNode.Children)
            for n = 1:length(rts)
                load([app.rootdir, 'Routines\', rts(n).name])
                uitreenode(app.RoutinesNode,  ...
                    'Text', rts(n).name, ...
                    'NodeData', rt, ...
                    'Tag', 'R' ...
                    );
            end
            expand(app.RoutinesNode)
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            addpath(genpath('System'), genpath('Experiments'));
            format long
            
            %% Splash
            im = imread('System\Icons\Splash.png'); %load splash image
            imsize = size(im); %get original image size
            imsize = imsize*(1000/max(imsize)); %transform so it fits within a 1000*1000 box
            
            splash = figure('Name', '');
            splash.MenuBar = 'none';
            splash.NumberTitle = 'off';
            splash.NumberTitle = 'off';
            splash.Position = [200,200,imsize(2),imsize(1)];
            
            splaxis = axes('Parent',splash);
            splaxis.Units = 'normalized';
            splaxis.Position = [0 0 1 1];
            image(splaxis, im);
            splaxis.TickDir = 'out';
            
            movegui(splash,'center'); %move to centre
            set(splash,'Visible','on')
            %%
            
            pause(1) %load content (placeholder)
            
            delete(splash)
            movegui(app.UIFigure,'center')
            set(0,'units','pixels');
            sdim = get(groot, 'Screensize');
            app.UIFigure.Position = sdim + [99, 99, -200, -200];
            app.TabGroup.SelectedTab = app.NoneTab;
            
            app.UIFigure.Visible = 'on';
            
        end

        % Callback function: EditProcedureButton, EditProcedureMenu
        function EditProcedureButtonPushed(app, event)
            load([app.rootdir, '\Design\Procedure.mat'])
            p = Procedure;
            p.loadobj(pr)
            p.rootdir = app.rootdir;
            waitfor(p)
            loadproc(app, [app.rootdir, '\Design\Procedure.mat'])
        end

        % Selection changed function: ProcedureTree
        function ProcedureTreeSelectionChanged(app, event)
            node = event.SelectedNodes;
            
            switch node.Tag
                case {'If', 'Else'}
                    app.ActionDetails.Value = [...
                        'If ' node.NodeData.IfDropDown ...
                        ' is ' node.NodeData.IfIsDropDown ...
                        ' ' node.NodeData.IfComparedToField '...'];
                    
                case 'While'
                    app.ActionDetails.Value = [...
                        'Reapeat until ' node.NodeData.WhileSubject ...
                        ' is ' node.NodeData.WhileIsDropDown ...
                        ' ' node.NodeData.WhileComparedToField ...
                        ', each time adding ' num2str(node.NodeData.WhileIntervalSpinner) ...
                        ' to the variable ' node.NodeData.WhileIteratorField ...
                        ' starting at ' num2str(node.NodeData.WhileStartSpinner)];
                    
                case 'For'
                    app.ActionDetails.Value = [...
                        'Repeat ' num2str(node.NodeData.ForRepeatsSpinner) ' times,' ...
                        ' each time adding ' num2str(node.NodeData.ForIntervalSpinner) ...
                        ' to the variable "' node.NodeData.ForIteratorField '",' ...
                        ' starting at ' num2str(node.NodeData.ForStartSpinner)...
                        ];
                    
                case 'Random'
                    if node.NodeData.RandFullButton
                        randType = ' fully';
                    elseif node.NodeData.RandEvenButton
                        randType = 'n evenly';
                    end
                    
                    app.ActionDetails.Value = [...
                        'Run ' num2str(node.NodeData.RandSpinner) 'of the contained components'...
                        ', in a' randType ' random order' ...
                        ];
                    
                case 'R'
                    app.ActionDetails.Value = [node.NodeData.Name ': ' num2str(node.NodeData.Dur) ' ms, ' num2str(length(node.NodeData.Elements)) ' Elements'];
                    
                case 'Code'
                    app.ActionDetails.Value = [num2str(length(node.NodeData.Code)), ' lines of code'];
                    
                case 'Variable'
                    app.ActionDetails.Value = [...
                        'Set ' node.NodeData.Variable ...
                        ' to equal ' node.NodeData.Value ...
                        ];
                    
                otherwise
                    app.ActionDetails.Value = '';
                    
            end
        end

        % Menu selected function: NewExperimentMenu
        function NewExperiment(app, event)
            folder = uigetdir("Experiments\");
            name = inputdlg('Enter a name for your experiment in the box below',"Experiment Name",1,"Experiment");
            app.ElementName.Value = name{:};
            app.rootdir = [folder, '\', name{:}, '\'];
            mkdir(app.rootdir);
            mkdir(app.rootdir, 'Design');
            mkdir(app.rootdir, 'Elements');
            mkdir(app.rootdir, 'Routines');
            mkdir(app.rootdir, 'Stimuli');
            mkdir(app.rootdir, 'Data');
            de = Design;
            de.rootdir = app.rootdir;
            waitfor(de)
            try
                loaddes(app, [app.rootdir, 'Design\Design.mat'])
            catch
                des = struct();
                save([app.rootdir, 'Design\Design.mat'], 'des');
            end
            try
                loadproc(app, [app.rootdir, 'Design\Procedure.mat'])
            catch
                pr = struct();
                save([app.rootdir, 'Design\Procedure.mat'], 'pr');
            end
            try
                loadcomp(app)
            catch
            end
            app.ElementName.Enable = 'on';
            app.RunButton.Enable = 'on';
            app.DesignLabel.Enable = 'on';
            app.ProcedureLabel.Enable = 'on';
            app.Tree.Enable = 'on';
            app.ProcedureTree.Enable = 'on';
            app.EditProcedureButton.Enable = 'on';
            app.EditDesignButton.Enable = 'on';
            app.TabGroup.Visible = 'on';
            app.ComponentTree.Enable = 'on';
            app.NewButton.Enable = 'on';
            app.ComponentManagerLabel.Enable = 'on';
            
        end

        % Menu selected function: OpenExperimentMenu
        function OpenExperimentMenuSelected(app, event)
            app.rootdir = [uigetdir(), '\'];
            try
                loaddes(app, [app.rootdir, 'Design\Design.mat'])
            end
            try
                loadproc(app, [app.rootdir, 'Design\Procedure.mat'])
            end
            try
                loadcomp(app)
            end
            app.ElementName.Enable = 'on';
            app.RunButton.Enable = 'on';
            app.DesignLabel.Enable = 'on';
            app.ProcedureLabel.Enable = 'on';
            app.Tree.Enable = 'on';
            app.ProcedureTree.Enable = 'on';
            app.EditProcedureButton.Enable = 'on';
            app.EditDesignButton.Enable = 'on';
            app.TabGroup.Visible = 'on';
            app.TabGroup.Visible = 'on';
            app.ComponentTree.Enable = 'on';
            app.NewButton_2.Enable = 'on';
            app.ComponentManagerLabel.Enable = 'on';
            
            nm = strsplit(app.rootdir, '\');
            app.ElementName.Value = nm{end-1};
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            spl = strsplit(app.rootdir, '\');
            subrt = strjoin(spl(1:end-2), '\');
            try
                movefile(app.rootdir, [subrt, '\', app.ElementName.Value])
            end

        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            newdir = [uigetdir("Experiments\"), '\'];
            name = inputdlg('Enter a new name for this copy of your experiment in the box below',"New Experiment Name",1,"NewExperiment");
            copyfile(app.rootdir, [newdir, name{:}])
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.ElementName.Enable = 'off';
            app.RunButton.Enable = 'off';
            app.DesignLabel.Enable = 'off';
            app.ProcedureLabel.Enable = 'off';
            app.Tree.Enable = 'off';
            app.ProcedureTree.Enable = 'off';
            app.EditProcedureButton.Enable = 'off';
            app.EditDesignButton.Enable = 'off';
            app.TabGroup.Visible = 'off';
            app.ComponentTree.Enable = 'off';
            app.NewButton.Enable = 'off';
            app.ComponentManagerLabel.Enable = 'off';

        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app)
        end

        % Callback function: ElementButton, NewElementMenu
        function NewElementMenuSelected(app, event)
            app.NewButton_2.Value = false;
            app.AddComponentPanel.Visible = false;
            el = Element;
            el.rootdir = app.rootdir;
            %waitfor(el)
            loadcomp(app)
            
        end

        % Callback function: NewRoutineMenu, RoutineButton
        function NewRoutineMenuSelected(app, event)
            app.NewButton_2.Value = false;
            app.AddComponentPanel.Visible = false;
            rt = Routine;
            rt.rootdir = app.rootdir;
            %waitfor(rt)
            loadcomp(app)
        end

        % Callback function: EditDesignButton, EditDesignMenu
        function EditDesignMenuSelected(app, event)
            load([app.rootdir, '\Design\Design.mat'])
            d = Design;
            d.loadobj(des)
            d.rootdir = app.rootdir;
            %waitfor(d)
            loaddes(app)
        end

        % Menu selected function: ImportElementMenu
        function ImportElementMenuSelected(app, event)
            [srcnm, srcdir] = uigetfile();
            copyfile([srcdir, srcnm], [app.rootdir, 'Elements\', srcnm]);
            loadcomp(app)
        end

        % Menu selected function: ImportRoutineMenu
        function ImportRoutineMenuSelected(app, event)
            [srcnm, srcdir] = uigetfile();
            copyfile([srcdir, srcnm], [app.rootdir, 'Routines\', srcnm]);
            loadcomp(app)
        end

        % Selection changed function: ComponentTree
        function ComponentTreeSelectionChanged(app, event)
            switch event.SelectedNodes.Parent
                case app.StimuliNode
                    app.TabGroup.SelectedTab = app.StimulusTab;
                    
                    [img.Px, img.Map, img.A] = imread(event.SelectedNodes.NodeData);
                    img.Dim = size(img.Px);
                    im = imshow(...
                        img.Px, ...
                        img.Map, ...
                        'Parent', app.StimPreview);
                    im.XData = im.XData + (im.Parent.XLim(2) - im.XData(2))/2;
                    im.YData = im.YData + (im.Parent.YLim(2) - im.YData(2))/2;
                    
                case app.ElementsNode_2
                    app.TabGroup.SelectedTab = app.ElementTab;
                    el = event.SelectedNodes.NodeData;
                    app.ElementNameLabel.Text = el.ElementName;
                    app.DurationLabel.Text = [num2str(el.Dur) ' ms'];
                    try
                        for s = 1:length({el.Stimuli.Dir})
                            stim = strsplit(el.Stimuli(s).Dir, '\');
                            app.UITable.Data{s,1} = stim{end};
                        end
                    end
                    try
                        for m = 1:length({el.Measures.Dir})
                            meas = strsplit(el.Measures(m).Measures, '\');
                            app.UITable.Data{m,1} = meas{end};
                        end
                    end
                    try
                        for s = 1:length({el.Coding.Name})
                            code = strsplit(el.Coding(s).Name, '\');
                            app.UITable.Data{s,1} = code{end};
                        end
                    end
                    app.RGBLabel.Text = ['[' num2str(el.Colour(1)) ', ' num2str(el.Colour(2)) ', ' num2str(el.Colour(1)) ']'];
                    app.BgPreview.BackgroundColor = el.Colour;
                    
                case app.RoutinesNode
                    app.TabGroup.SelectedTab = app.RoutineTab;
                    
                    app.RoutineNameLabel.Text = app.ComponentTree.SelectedNodes.Text;
                    app.RoutineDuration.Text = [num2str(app.ComponentTree.SelectedNodes.NodeData.Dur), 'ms'];
                    for el = app.ComponentTree.SelectedNodes.NodeData.Elements
                        try
                            delete(app.RoutineElements.Children)
                        end
                        if el.el.Dur == inf
                            durtag = [num2str(round(el.Start)), 'ms -> End'];
                        else
                            durtag = [num2str(round(el.Start)), 'ms -> ' num2str(el.Start + el.el.Dur), 'ms'];
                        end
                        uitreenode(app.RoutineElements, ...
                            'Text', el.el.ElementName, ...
                            'Tag', 'E', ...
                            'NodeData',  durtag ...
                            );
                    end
                    app.RoutineFactors.Data = app.ComponentTree.SelectedNodes.NodeData.Factors;
                    app.ElDurLabel.Text = '';
                    
                case app.ComponentTree
                    app.TabGroup.SelectedTab = app.NoneTab;
            end
        end

        % Value changed function: NewButton_2
        function NewButton_2ValueChanged(app, event)
            app.AddComponentPanel.Visible = event.Value;
            
        end

        % Button pushed function: StimulusButton
        function StimulusButtonPushed(app, event)
            app.AddComponentPanel.Visible = false;
            [name, path] = uigetfile([app.rootdir, '\*.*']);
            try
                movefile([path, name], [app.rootdir, 'Stimuli\', name]);
            catch
                error('Could not move file');
            end
            
            loadcomp(app)
            app.NewButton_2.Value = false;
        end

        % Selection changed function: RoutineElements
        function RoutineElementsSelectionChanged(app, event)
            app.ElDurLabel.Text = app.RoutineElements.SelectedNodes.NodeData;
        end

        % Button pushed function: EditButton, EditButton_2
        function EditComponent(app, event)
            switch app.ComponentTree.SelectedNodes.Parent
                case app.ElementsNode_2
                    load([app.rootdir, 'Elements\', app.ComponentTree.SelectedNodes.Text])
                    e = Element;
                    e.rootdir = app.rootdir;
                    e.loadobj(el)
                    
                case app.RoutinesNode
                    load([app.rootdir, 'Routines\', app.ComponentTree.SelectedNodes.Text])
                    r = Routine;
                    r.rootdir = app.rootdir;
                    r.loadobj(rt)
            end
            
        end

        % Button pushed function: DeleteButton, DeleteButton_2, 
        % DeleteButton_3
        function DeleteComponent(app, event)
            switch questdlg('Are you sure you wish to delete this component? This cannot be undone.','Warning','Yes','No','No')
                case 'Yes'
                    switch app.ComponentTree.SelectedNodes.Parent
                        case app.ElementsNode_2
                            delete([app.rootdir, 'Elements\', app.ComponentTree.SelectedNodes.Text]);
                        case app.RoutinesNode
                            delete([app.rootdir, 'Routines\', app.ComponentTree.SelectedNodes.Text]);
                        case app.StimuliNode
                            delete([app.rootdir, 'Stimuli\', app.ComponentTree.SelectedNodes.Text]);
                    end
                    delete(app.ComponentTree.SelectedNodes);
                case 'No'
                    
            end
            
            loadcomp(app)
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab = event.OldValue; %prevent changing tabs manually
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            loadcomp(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewExperimentMenu = uimenu(app.FileMenu);
            app.NewExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @NewExperiment, true);
            app.NewExperimentMenu.Text = 'New Experiment...';
            app.OpenExperimentMenu = uimenu(app.FileMenu);
            app.OpenExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenExperimentMenuSelected, true);
            app.OpenExperimentMenu.Text = 'Open Experiment...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.ComponentsMenu = uimenu(app.UIFigure);
            app.ComponentsMenu.Text = 'Components';
            app.StimuliMenu = uimenu(app.ComponentsMenu);
            app.StimuliMenu.Text = 'Stimuli';
            app.NewStimulusMenu = uimenu(app.StimuliMenu);
            app.NewStimulusMenu.Text = 'New Stimulus';
            app.ElementsMenu = uimenu(app.ComponentsMenu);
            app.ElementsMenu.Text = 'Elements';
            app.NewElementMenu = uimenu(app.ElementsMenu);
            app.NewElementMenu.MenuSelectedFcn = createCallbackFcn(app, @NewElementMenuSelected, true);
            app.NewElementMenu.Text = 'New Element';
            app.ImportElementMenu = uimenu(app.ElementsMenu);
            app.ImportElementMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportElementMenuSelected, true);
            app.ImportElementMenu.Text = 'Import Element';
            app.RoutinesMenu = uimenu(app.ComponentsMenu);
            app.RoutinesMenu.Text = 'Routines';
            app.NewRoutineMenu = uimenu(app.RoutinesMenu);
            app.NewRoutineMenu.MenuSelectedFcn = createCallbackFcn(app, @NewRoutineMenuSelected, true);
            app.NewRoutineMenu.Text = 'New Routine';
            app.ImportRoutineMenu = uimenu(app.RoutinesMenu);
            app.ImportRoutineMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportRoutineMenuSelected, true);
            app.ImportRoutineMenu.Text = 'Import Routine';
            app.EditDesignMenu = uimenu(app.ComponentsMenu);
            app.EditDesignMenu.MenuSelectedFcn = createCallbackFcn(app, @EditDesignMenuSelected, true);
            app.EditDesignMenu.Text = 'Edit Design...';
            app.EditProcedureMenu = uimenu(app.ComponentsMenu);
            app.EditProcedureMenu.MenuSelectedFcn = createCallbackFcn(app, @EditProcedureButtonPushed, true);
            app.EditProcedureMenu.Text = 'Edit Procedure...';
            app.ExperimentMenu = uimenu(app.UIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.RunMenu = uimenu(app.ExperimentMenu);
            app.RunMenu.Text = 'Run';
            app.StopMenu = uimenu(app.ExperimentMenu);
            app.StopMenu.Text = 'Stop';
            app.CompileMenu = uimenu(app.ExperimentMenu);
            app.CompileMenu.Text = 'Compile...';
            app.Tree = uitree(app.UIFigure);
            app.Tree.Enable = 'off';
            app.WithinSubjectsFactorsNode = uitreenode(app.Tree);
            app.WithinSubjectsFactorsNode.NodeData = 'W';
            app.WithinSubjectsFactorsNode.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsNode = uitreenode(app.Tree);
            app.BetweenSubjectsFactorsNode.NodeData = 'B';
            app.BetweenSubjectsFactorsNode.Text = 'Between-Subjects Factors';
            app.MeasuresNode = uitreenode(app.Tree);
            app.MeasuresNode.NodeData = 'M';
            app.MeasuresNode.Text = 'Measures';
            app.Node = uitreenode(app.Tree);
            app.Node.NodeData = 'N';
            app.Node.Text = '';
            app.EditDesignButton = uibutton(app.UIFigure, 'push');
            app.EditDesignButton.ButtonPushedFcn = createCallbackFcn(app, @EditDesignMenuSelected, true);
            app.EditDesignButton.Enable = 'off';
            app.EditDesignButton.Text = 'Edit Design';
            app.ElementName = uieditfield(app.UIFigure, 'text');
            app.ElementName.Enable = 'off';
            app.ElementName.Value = 'Experiment Name';
            app.ElementTree_2 = uitree(app.UIFigure);
            app.ManageDataButton = uibutton(app.UIFigure, 'push');
            app.ManageDataButton.Text = 'Manage Data';
            app.DesignLabel = uilabel(app.UIFigure);
            app.DesignLabel.Enable = 'off';
            app.DesignLabel.Text = 'Design';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ProcedureTree = uitree(app.UIFigure);
            app.ProcedureTree.SelectionChangedFcn = createCallbackFcn(app, @ProcedureTreeSelectionChanged, true);
            app.ProcedureTree.Enable = 'off';
            app.PTBlank = uitreenode(app.ProcedureTree);
            app.PTBlank.NodeData = 'N';
            app.PTBlank.Text = '';
            app.ProcedureLabel = uilabel(app.UIFigure);
            app.ProcedureLabel.Enable = 'off';
            app.ProcedureLabel.Text = 'Procedure';
            app.EditProcedureButton = uibutton(app.UIFigure, 'push');
            app.EditProcedureButton.ButtonPushedFcn = createCallbackFcn(app, @EditProcedureButtonPushed, true);
            app.EditProcedureButton.Enable = 'off';
            app.EditProcedureButton.Text = 'Edit Procedure';
            app.Panel = uipanel(app.UIFigure);
            app.ComponentManagerLabel = uilabel(app.Panel);
            app.ComponentManagerLabel.Enable = 'off';
            app.ComponentManagerLabel.Text = 'Component Manager';
            app.TabGroup = uitabgroup(app.Panel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.StimulusTab = uitab(app.TabGroup);
            app.StimulusTab.Title = 'Stimulus';
            app.StimPreview = uiaxes(app.StimulusTab);
            app.StimPreview.XTick = [];
            app.StimPreview.YTick = [];
            app.StimPreview.Visible = 'off';
            app.DeleteButton_2 = uibutton(app.StimulusTab, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton_2.Enable = 'off';
            app.DeleteButton_2.Text = 'Delete';
            app.ElementTab = uitab(app.TabGroup);
            app.ElementTab.Title = 'Element';
            app.DeleteButton = uibutton(app.ElementTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.EditButton = uibutton(app.ElementTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditComponent, true);
            app.EditButton.Text = 'Edit';
            app.UITable = uitable(app.ElementTab);
            app.UITable.ColumnName = {'Stimuli'; 'Responses'; 'Code'};
            app.UITable.RowName = {};
            app.BgPreview = uiaxes(app.ElementTab);
            app.BgPreview.XLim = [1 750];
            app.BgPreview.YLim = [1 500];
            app.BgPreview.XTick = [];
            app.BgPreview.YTick = [];
            app.BgPreview.Visible = 'off';
            app.BackgroundColourLabel = uilabel(app.ElementTab);
            app.BackgroundColourLabel.Text = 'Background Colour';
            app.ContentsLabel = uilabel(app.ElementTab);
            app.ContentsLabel.Text = 'Contents';
            app.ElementNameLabel = uilabel(app.ElementTab);
            app.ElementNameLabel.Text = 'Name';
            app.DurationLabel = uilabel(app.ElementTab);
            app.DurationLabel.Text = 'Duration';
            app.RGBLabel = uilabel(app.ElementTab);
            app.RGBLabel.Text = '[R, G, B]';
            app.RoutineTab = uitab(app.TabGroup);
            app.RoutineTab.Title = 'Routine';
            app.RoutineNameLabel = uilabel(app.RoutineTab);
            app.RoutineNameLabel.Text = 'Name';
            app.RoutineDuration = uilabel(app.RoutineTab);
            app.RoutineDuration.Text = 'Duration';
            app.ElementsLabel = uilabel(app.RoutineTab);
            app.ElementsLabel.Text = 'Elements';
            app.RoutineElements = uitree(app.RoutineTab);
            app.RoutineElements.SelectionChangedFcn = createCallbackFcn(app, @RoutineElementsSelectionChanged, true);
            app.ElDurLabel = uilabel(app.RoutineTab);
            app.ElDurLabel.Text = '';
            app.FactorsLabel = uilabel(app.RoutineTab);
            app.FactorsLabel.Text = 'Factors';
            app.RoutineFactors = uitable(app.RoutineTab);
            app.RoutineFactors.ColumnName = {'Factor'; 'Level'};
            app.RoutineFactors.RowName = {};
            app.DeleteButton_3 = uibutton(app.RoutineTab, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton_3.Enable = 'off';
            app.DeleteButton_3.Text = 'Delete';
            app.EditButton_2 = uibutton(app.RoutineTab, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditComponent, true);
            app.EditButton_2.Text = 'Edit';
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = ' ';
            app.ComponentTree = uitree(app.Panel);
            app.ComponentTree.SelectionChangedFcn = createCallbackFcn(app, @ComponentTreeSelectionChanged, true);
            app.ComponentTree.Enable = 'off';
            app.StimuliNode = uitreenode(app.ComponentTree);
            app.StimuliNode.Text = 'Stimuli';
            app.ElementsNode_2 = uitreenode(app.ComponentTree);
            app.ElementsNode_2.Text = 'Elements';
            app.RoutinesNode = uitreenode(app.ComponentTree);
            app.RoutinesNode.Text = 'Routines';
            app.Node_2 = uitreenode(app.ComponentTree);
            app.Node_2.Text = '';
            app.AddComponentPanel = uipanel(app.Panel);
            app.AddComponentPanel.Visible = 'off';
            app.StimulusButton = uibutton(app.AddComponentPanel, 'push');
            app.StimulusButton.ButtonPushedFcn = createCallbackFcn(app, @StimulusButtonPushed, true);
            app.StimulusButton.Text = 'Stimulus';
            app.ElementButton = uibutton(app.AddComponentPanel, 'push');
            app.ElementButton.ButtonPushedFcn = createCallbackFcn(app, @NewElementMenuSelected, true);
            app.ElementButton.Text = 'Element';
            app.RoutineButton = uibutton(app.AddComponentPanel, 'push');
            app.RoutineButton.ButtonPushedFcn = createCallbackFcn(app, @NewRoutineMenuSelected, true);
            app.RoutineButton.Text = 'Routine';
            app.NewButton_2 = uibutton(app.Panel, 'state');
            app.NewButton_2.ValueChangedFcn = createCallbackFcn(app, @NewButton_2ValueChanged, true);
            app.NewButton_2.Enable = 'off';
            app.NewButton_2.Text = 'New...';
            app.RefreshButton = uibutton(app.Panel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Enable = 'off';
            app.DataLabel.Text = 'Data';
            app.ActionDetails = uitextarea(app.UIFigure);
            app.ActionDetails.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pedestal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end