classdef Routine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Timeline             matlab.ui.control.UIAxes
        StartatSlider        matlab.ui.control.Slider
        RoutinePanel         matlab.ui.container.Panel
        RoutineElements      matlab.ui.container.Tree
        ElementsNode         matlab.ui.container.TreeNode
        Node                 matlab.ui.container.TreeNode
        AddButton            matlab.ui.control.Button
        RoutineName          matlab.ui.control.EditField
        CancelButton         matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        ElementPanel         matlab.ui.container.Panel
        ElementListBoxLabel  matlab.ui.control.Label
        ElementListBox       matlab.ui.control.ListBox
        DeleteButton         matlab.ui.control.Button
        StartSpinnerLabel    matlab.ui.control.Label
        StartSpinner         matlab.ui.control.Spinner
        FactorsButton        matlab.ui.control.Button
        DurationmsLabel      matlab.ui.control.Label
        DurationSpinner      matlab.ui.control.Spinner
        NoResponseLimit      matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        rootdir % Description
        Factors % Description
        Parent % Description
    end
    
    methods (Access = public)
        
        function OrderEmts(app)
            app.Timeline.YLim(2) = length(app.ElementsNode.Children) + 1; % Set upper limit of Y axis to equal the number of elements + 1 so there is room
            for el = app.ElementsNode.Children' % For each element
                n = find(el == app.ElementsNode.Children); % Get element number
                el.NodeData.Line.YData = app.Timeline.YLim(2) - [n, n]; % Move line to corresponding position
            end
            app.Timeline.YTick = 1:app.Timeline.YLim(2); % Make sure each line is ticked
        end
    
        function saveobj(app)
            rt = struct(...
                'Name', app.RoutineName.Value, ...
                'Dur', app.DurationSpinner.Value, ...
                'Factors', {app.Factors}, ...
                'Elements', {app.ElementsNode.Children.NodeData} ...
                );    
            save([app.rootdir, 'Routines\' app.RoutineName.Value], 'rt');
        end
    
        function loadobj(app, rt)
            app.RoutineName.Value = rt.Name;
            app.RoutineName.Enable = 'off';
            app.DurationSpinner.Value = rt.Dur;
            RoutineDurationSpinnerValueChanged(app, struct('Value', rt.Dur));
            app.Factors = rt.Factors;
            
            for rt_el = rt.Elements
                node = uitreenode(app.ElementsNode, ...
                    'Text', rt_el.el.ElementName, ...
                    'NodeData', rt_el, ...
                    'Tag', 'E' ...
                    );
                if rt_el.el.Dur == Inf
                    dur = 1000000;
                else
                    dur = node.NodeData.el.Dur;
                end
                layer = app.Timeline.YLim(2);
                app.Timeline.YLim(2) = layer + 1;
                node.NodeData.Line = plot(app.Timeline, ...
                    [rt_el.Start,dur], [layer,layer], ...
                    '-d', 'Color', [0,33/255,71/255], ...
                    'LineWidth', 10); %draw element
                hold(app.Timeline, 'on');
            end
            expand(app.ElementsNode);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off'; % Hide window
            
            movegui(app.UIFigure,'center'); % Center window
            
            app.UIFigure.Visible = 'on'; % Show window
            
        end

        % Button pushed function: AddButton
        function AddElement(app, event)
            node = uitreenode(app.ElementsNode, ...
                'Text', '..', ...
                'NodeData', struct(...
                'el', struct(), ...
                'Line', plot(app.Timeline, 0, 'Visible', 'off'), ... % Create invisible line to represent element
                'Start', 1 ...
                )); % Create placeholder node

            expand(app.ElementsNode);
            OrderEmts(app)
            app.RoutineElements.SelectedNodes = node;
            ElementSelected(app, app.RoutineElements);
            
        end

        % Callback function: StartSpinner, StartSpinner, StartatSlider
        function StartValueChanged(app, event)
            node = app.RoutineElements.SelectedNodes; % Get element node
            dur = node.NodeData.el.Dur; % Get element duration
            node.NodeData.Line.XData = [event.Value,event.Value + dur]; % Move line to point specified
            node.NodeData.Start = event.Value; % Update node data
            
            set([app.StartSpinner, app.StartatSlider], ... % Update slider and spinner
                'Value', event.Value ...
                );
            drawnow
            
        end

        % Selection changed function: RoutineElements
        function ElementSelected(app, event)
            set([app.Timeline.Children], 'Color', [0 0.1294 0.2784]); % Un-highlight all lines
            set([app.ElementPanel.Children], 'Visible', event.SelectedNodes.Parent == app.ElementsNode); % Show / hide controls
            try
                set(app.StartatSlider, 'Visible', ~strcmp(app.ElementListBox.Value, '..')); % Show / hide start slider
            end
            
            if event.SelectedNodes.Parent == app.ElementsNode % If valid node is selected
                ln = event.SelectedNodes.NodeData.Line; % Get line handle for selected element
                set([app.StartatSlider, app.StartSpinner], ... % Update start spinner & slider from node data
                    'Value', event.SelectedNodes.NodeData.Start ...
                    );
                ln.Color = [0.90,0.00,0.49]; % Highlight line
                
                dir_el = dir([app.rootdir, 'Elements']); % Get list of elements
                app.ElementListBox.Items = erase({dir_el(2:end).name}, '.mat'); % Set items of list box to equal these elements, without file extensions
                try
                    app.ElementListBox.Value = event.SelectedNodes.NodeData.el.ElementName; % Select item specified by node data
                end
            end
            
        end

        % Button pushed function: DeleteButton
        function DeleteElement(app, event)
            try
                delete(app.RoutineElements.SelectedNodes.NodeData.Line)
            end
            delete(app.RoutineElements.SelectedNodes)
            OrderEmts(app)
            
            %             if ~isempty([app.RoutineElements.Children.NodeData])
            %                 OrderEmts(app)
            %             else
            %                 app.StartatSlider.Visible = 'off';
            %             end
        end

        % Callback function: DurationSpinner, DurationSpinner
        function RoutineDurationSpinnerValueChanged(app, event)
        app.Timeline.XLim = [0, event.Value];
        app.StartatSlider.Limits = [0, event.Value];
        app.StartSpinner.Limits = [0, event.Value];
        
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
    delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
    saveobj(app)
    app.Parent.loadcomp()
    delete(app)
        end

        % Value changed function: ElementListBox
        function ElementListBoxValueChanged(app, event)
            node = app.RoutineElements.SelectedNodes;
            try
                delete(node.NodeData.Line);
            end
            switch event.Value
                case '..'
                    node.Text = event.Value;
                    node.NodeData = struct(...
                        'ElementName', '..', ...
                        'el', struct('Dur', 1), ...
                        'Line', struct(...
                        'XData', [], ...
                        'YData', [], ...
                        'Color', [[] [] []] ...
                        ), ...
                        'Start', node.NodeData.Start ...
                        ); 
                    app.StartatSlider.Visible = false;
                otherwise
                    load([app.rootdir, 'Elements\' event.Value]);
                    node.Text = event.Value;
                    node.NodeData.el = el;
                    if node.NodeData.el.Dur == Inf
                        node.NodeData.el.Dur = 1000000;
                    end
                    app.Timeline.YLim(2) = length(app.ElementsNode.Children) + 1;
                    app.RoutineElements.SelectedNodes.NodeData.Line = plot(app.Timeline, ...
                        [0,node.NodeData.el.Dur], ...
                        [0,0], ...
                        '-d', 'Color', [0.90,0.00,0.49], ...
                        'LineWidth', 10, ...
                        'Visible', 'off'); %draw element
                    StartValueChanged(app, struct('Value', node.NodeData.Start));
                    app.StartatSlider.Visible = true;
                    hold(app.Timeline, 'on');
                    OrderEmts(app)
                    app.RoutineElements.SelectedNodes.NodeData.Line.Visible = 'on';
            end
            
        end

        % Button pushed function: FactorsButton
        function EditFactors(app, event)
            f = Factors;
            f.Parent = app;
            f.init();
            if ~isempty(app.Factors)
                f.loadobj(app.Factors);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Timeline = uiaxes(app.UIFigure);
            title(app.Timeline, '')
            xlabel(app.Timeline, {'Time (ms)'; ''})
            ylabel(app.Timeline, '')
            app.Timeline.XLim = [0 100];
            app.Timeline.YLim = [0 1];
            app.Timeline.YTick = 1;
            app.Timeline.YTickLabel = '';
            app.StartatSlider = uislider(app.UIFigure);
            app.StartatSlider.MajorTicks = [];
            app.StartatSlider.ValueChangedFcn = createCallbackFcn(app, @StartValueChanged, true);
            app.StartatSlider.MinorTicks = [];
            app.StartatSlider.Visible = 'off';
            app.RoutinePanel = uipanel(app.UIFigure);
            app.RoutineElements = uitree(app.RoutinePanel);
            app.RoutineElements.SelectionChangedFcn = createCallbackFcn(app, @ElementSelected, true);
            app.ElementsNode = uitreenode(app.RoutineElements);
            app.ElementsNode.Text = 'Elements';
            app.Node = uitreenode(app.RoutineElements);
            app.Node.Text = '';
            app.AddButton = uibutton(app.RoutinePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddElement, true);
            app.AddButton.Text = 'Add';
            app.RoutineName = uieditfield(app.RoutinePanel, 'text');
            app.RoutineName.Value = 'Routine Name';
            app.CancelButton = uibutton(app.RoutinePanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.RoutinePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ElementPanel = uipanel(app.RoutinePanel);
            app.ElementListBoxLabel = uilabel(app.ElementPanel);
            app.ElementListBoxLabel.Text = 'Element:';
            app.ElementListBox = uilistbox(app.ElementPanel);
            app.ElementListBox.Items = {};
            app.ElementListBox.ValueChangedFcn = createCallbackFcn(app, @ElementListBoxValueChanged, true);
            app.ElementListBox.Value = {};
            app.DeleteButton = uibutton(app.ElementPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteElement, true);
            app.DeleteButton.Text = 'Delete';
            app.StartSpinnerLabel = uilabel(app.ElementPanel);
            app.StartSpinnerLabel.Text = 'Start:  ';
            app.StartSpinner = uispinner(app.ElementPanel);
            app.StartSpinner.Step = 100;
            app.StartSpinner.ValueChangingFcn = createCallbackFcn(app, @StartValueChanged, true);
            app.StartSpinner.Limits = [0 100];
            app.StartSpinner.RoundFractionalValues = 'on';
            app.StartSpinner.ValueChangedFcn = createCallbackFcn(app, @StartValueChanged, true);
            app.FactorsButton = uibutton(app.RoutinePanel, 'push');
            app.FactorsButton.ButtonPushedFcn = createCallbackFcn(app, @EditFactors, true);
            app.FactorsButton.Text = 'Factors...';
            app.DurationmsLabel = uilabel(app.UIFigure);
            app.DurationmsLabel.Text = 'Duration (ms):';
            app.DurationSpinner = uispinner(app.UIFigure);
            app.DurationSpinner.Step = 100;
            app.DurationSpinner.ValueChangingFcn = createCallbackFcn(app, @RoutineDurationSpinnerValueChanged, true);
            app.DurationSpinner.RoundFractionalValues = 'on';
            app.DurationSpinner.ValueChangedFcn = createCallbackFcn(app, @RoutineDurationSpinnerValueChanged, true);
            app.DurationSpinner.Value = 100;
            app.NoResponseLimit = uicheckbox(app.UIFigure);
            app.NoResponseLimit.Text = '∞';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Routine

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end